classdef DoubleChamp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        Image1                  matlab.ui.control.Image
        HealthLabel             matlab.ui.control.Label
        ResourceLabel           matlab.ui.control.Label
        AttackDamageLabel       matlab.ui.control.Label
        ArmorLabel              matlab.ui.control.Label
        AttackSpeedLabel        matlab.ui.control.Label
        CriticalChanceLabel     matlab.ui.control.Label
        HealthVal               matlab.ui.control.Label
        ResourceVal             matlab.ui.control.Label
        AttackDamageVal         matlab.ui.control.Label
        ArmorVal                matlab.ui.control.Label
        AttackSpeedVal          matlab.ui.control.Label
        CriticalChanceVal       matlab.ui.control.Label
        AbilityPowerLabel       matlab.ui.control.Label
        MagicResistanceLabel    matlab.ui.control.Label
        AbilityHasteLabel       matlab.ui.control.Label
        MovementSpeedLabel      matlab.ui.control.Label
        AbilityPowerVal         matlab.ui.control.Label
        MagicResistanceVal      matlab.ui.control.Label
        AbilityHasteVal         matlab.ui.control.Label
        MovementSpeedVal        matlab.ui.control.Label
        Slot11Im                matlab.ui.control.Image
        Slot12Im                matlab.ui.control.Image
        Slot13Im                matlab.ui.control.Image
        Slot14Im                matlab.ui.control.Image
        Slot15Im                matlab.ui.control.Image
        Slot16Im                matlab.ui.control.Image
        Image2_1                matlab.ui.control.Image
        Image2_2                matlab.ui.control.Image
        Image2_3                matlab.ui.control.Image
        Image2_4                matlab.ui.control.Image
        Image2_5                matlab.ui.control.Image
        Image2_6                matlab.ui.control.Image
        Image2_7                matlab.ui.control.Image
        Image2_8                matlab.ui.control.Image
        Image2_9                matlab.ui.control.Image
        Image2_10               matlab.ui.control.Image
        Image2                  matlab.ui.control.Image
        HealthLabel_2           matlab.ui.control.Label
        ResourceLabel_2         matlab.ui.control.Label
        AttackDamageLabel_2     matlab.ui.control.Label
        ArmorLabel_2            matlab.ui.control.Label
        AttackSpeedLabel_2      matlab.ui.control.Label
        CriticalChanceLabel_2   matlab.ui.control.Label
        HealthVal_2             matlab.ui.control.Label
        ResourceVal_2           matlab.ui.control.Label
        AttackDamageVal_2       matlab.ui.control.Label
        ArmorVal_2              matlab.ui.control.Label
        AttackSpeedVal_2        matlab.ui.control.Label
        CriticalChanceVal_2     matlab.ui.control.Label
        AbilityPowerLabel_2     matlab.ui.control.Label
        MagicResistanceLabel_2  matlab.ui.control.Label
        AbilityHasteLabel_2     matlab.ui.control.Label
        MovementSpeedLabel_2    matlab.ui.control.Label
        AbilityPowerVal_2       matlab.ui.control.Label
        MagicResistanceVal_2    matlab.ui.control.Label
        AbilityHasteVal_2       matlab.ui.control.Label
        MovementSpeedVal_2      matlab.ui.control.Label
        Slot21Im                matlab.ui.control.Image
        Slot22Im                matlab.ui.control.Image
        Slot23Im                matlab.ui.control.Image
        Slot24Im                matlab.ui.control.Image
        Slot25Im                matlab.ui.control.Image
        Slot26Im                matlab.ui.control.Image
        Image2_11               matlab.ui.control.Image
        Image2_12               matlab.ui.control.Image
        Image2_13               matlab.ui.control.Image
        Image2_14               matlab.ui.control.Image
        Image2_15               matlab.ui.control.Image
        Image2_16               matlab.ui.control.Image
        Image2_17               matlab.ui.control.Image
        Image2_18               matlab.ui.control.Image
        Image2_19               matlab.ui.control.Image
        Image2_20               matlab.ui.control.Image
        Q1                      matlab.ui.control.Image
        W1                      matlab.ui.control.Image
        E1                      matlab.ui.control.Image
        R1                      matlab.ui.control.Image
        MagicPenLabel           matlab.ui.control.Label
        MagicPenVal             matlab.ui.control.Label
        ArmorPenLabel           matlab.ui.control.Label
        ArmorPenVal             matlab.ui.control.Label
        LethalityLabel          matlab.ui.control.Label
        LethalityVal            matlab.ui.control.Label
        MagicPenLabel_2         matlab.ui.control.Label
        MagicPenVal_2           matlab.ui.control.Label
        ArmorPenLabel_2         matlab.ui.control.Label
        ArmorPenVal_2           matlab.ui.control.Label
        LethalityLabel_2        matlab.ui.control.Label
        LethalityVal_2          matlab.ui.control.Label
        Q2                      matlab.ui.control.Image
        W2                      matlab.ui.control.Image
        E2                      matlab.ui.control.Image
        R2                      matlab.ui.control.Image
        TabGroup                matlab.ui.container.TabGroup
        Tab1                    matlab.ui.container.Tab
        effectAttributeLabel1   matlab.ui.control.Label
        effectValueLabel1       matlab.ui.control.Label
        effectDealtLabel1       matlab.ui.control.Label
        Utility1                matlab.ui.control.Label
        Enact1                  matlab.ui.control.StateButton
        Tab2                    matlab.ui.container.Tab
        effectAttributeLabel2   matlab.ui.control.Label
        effectValueLabel2       matlab.ui.control.Label
        effectDealtLabel2       matlab.ui.control.Label
        Utility2                matlab.ui.control.Label
        Enact2                  matlab.ui.control.StateButton
        Tab3                    matlab.ui.container.Tab
        effectAttributeLabel3   matlab.ui.control.Label
        effectValueLabel3       matlab.ui.control.Label
        effectDealtLabel3       matlab.ui.control.Label
        Utility3                matlab.ui.control.Label
        Enact3                  matlab.ui.control.StateButton
        Tab4                    matlab.ui.container.Tab
        effectAttributeLabel4   matlab.ui.control.Label
        effectValueLabel4       matlab.ui.control.Label
        effectDealtLabel4       matlab.ui.control.Label
        Utility4                matlab.ui.control.Label
        Enact4                  matlab.ui.control.StateButton
        Tab5                    matlab.ui.container.Tab
        effectAttributeLabel5   matlab.ui.control.Label
        effectValueLabel5       matlab.ui.control.Label
        effectDealtLabel5       matlab.ui.control.Label
        Utility5                matlab.ui.control.Label
        Enact5                  matlab.ui.control.StateButton
        Tab6                    matlab.ui.container.Tab
        effectAttributeLabel6   matlab.ui.control.Label
        effectValueLabel6       matlab.ui.control.Label
        effectDealtLabel6       matlab.ui.control.Label
        Utility6                matlab.ui.control.Label
        Enact6                  matlab.ui.control.StateButton
        Tab7                    matlab.ui.container.Tab
        effectAttributeLabel7   matlab.ui.control.Label
        effectValueLabel7       matlab.ui.control.Label
        effectDealtLabel7       matlab.ui.control.Label
        Utility7                matlab.ui.control.Label
        Enact7                  matlab.ui.control.StateButton
        Tab8                    matlab.ui.container.Tab
        effectAttributeLabel8   matlab.ui.control.Label
        effectValueLabel8       matlab.ui.control.Label
        effectDealtLabel8       matlab.ui.control.Label
        Utility8                matlab.ui.control.Label
        Enact8                  matlab.ui.control.StateButton
        Tab9                    matlab.ui.container.Tab
        effectAttributeLabel9   matlab.ui.control.Label
        effectValueLabel9       matlab.ui.control.Label
        effectDealtLabel9       matlab.ui.control.Label
        Utility9                matlab.ui.control.Label
        Enact9                  matlab.ui.control.StateButton
        Tab10                   matlab.ui.container.Tab
        effectAttributeLabel10  matlab.ui.control.Label
        effectValueLabel10      matlab.ui.control.Label
        effectDealtLabel10      matlab.ui.control.Label
        Utility10               matlab.ui.control.Label
        Enact10                 matlab.ui.control.StateButton
        Slot11                  matlab.ui.control.DropDown
        Slot12                  matlab.ui.control.DropDown
        Slot13                  matlab.ui.control.DropDown
        Slot14                  matlab.ui.control.DropDown
        Slot15                  matlab.ui.control.DropDown
        Slot16                  matlab.ui.control.DropDown
        Slot21                  matlab.ui.control.DropDown
        Slot22                  matlab.ui.control.DropDown
        Slot23                  matlab.ui.control.DropDown
        Slot24                  matlab.ui.control.DropDown
        Slot25                  matlab.ui.control.DropDown
        Slot26                  matlab.ui.control.DropDown
        ChampionName1           matlab.ui.control.DropDown
        ChampionName2           matlab.ui.control.DropDown
        abi1                    matlab.ui.control.Label
        abi2                    matlab.ui.control.Label
        healthFull1             matlab.ui.control.Image
        resourceFull1           matlab.ui.control.Image
        healthFull2             matlab.ui.control.Image
        resourceFull2           matlab.ui.control.Image
        Effects                 matlab.ui.control.Label
        champ2Health            matlab.ui.control.Label
        champ1Health            matlab.ui.control.Label
        Redo1                   matlab.ui.control.Image
        Redo2                   matlab.ui.control.Image
        LvlDropDownLabel        matlab.ui.control.Label
        LvlDrop1                matlab.ui.control.DropDown
        LvlDropDownLabel_2      matlab.ui.control.Label
        LvlDrop2                matlab.ui.control.DropDown
        QSpinnerLabel           matlab.ui.control.Label
        QSpinner1               matlab.ui.control.Spinner
        ESpinnerLabel           matlab.ui.control.Label
        ESpinner1               matlab.ui.control.Spinner
        WSpinnerLabel           matlab.ui.control.Label
        WSpinner1               matlab.ui.control.Spinner
        RSpinnerLabel           matlab.ui.control.Label
        RSpinner1               matlab.ui.control.Spinner
        QSpinner_2Label         matlab.ui.control.Label
        QSpinner2               matlab.ui.control.Spinner
        ESpinner_2Label         matlab.ui.control.Label
        ESpinner2               matlab.ui.control.Spinner
        WSpinner_2Label         matlab.ui.control.Label
        WSpinner2               matlab.ui.control.Spinner
        RSpinner_2Label         matlab.ui.control.Label
        RSpinner2               matlab.ui.control.Spinner
        Image21                 matlab.ui.control.Image
        Image22                 matlab.ui.control.Image
        Image22_2               matlab.ui.control.Image
        Image22_3               matlab.ui.control.Image
        Image22_4               matlab.ui.control.Image
        Image21_2               matlab.ui.control.Image
        MejaiSlider1Label       matlab.ui.control.Label
        MejaiSlider1            matlab.ui.control.Spinner
        StackSlider1Label       matlab.ui.control.Label
        StackSlider1            matlab.ui.control.Spinner
        MejaiSlider2Label       matlab.ui.control.Label
        MejaiSlider2            matlab.ui.control.Spinner
        StackSlider2Label       matlab.ui.control.Label
        StackSlider2            matlab.ui.control.Spinner
        Patch111Label           matlab.ui.control.Label
        ButtonGroupR1           matlab.ui.container.ButtonGroup
        ButtonR11               matlab.ui.control.RadioButton
        ButtonR12               matlab.ui.control.RadioButton
        ButtonGroupE1           matlab.ui.container.ButtonGroup
        ButtonE11               matlab.ui.control.RadioButton
        ButtonE12               matlab.ui.control.RadioButton
        ButtonGroupW1           matlab.ui.container.ButtonGroup
        ButtonW11               matlab.ui.control.RadioButton
        ButtonW12               matlab.ui.control.RadioButton
        ButtonGroupQ1           matlab.ui.container.ButtonGroup
        ButtonQ11               matlab.ui.control.RadioButton
        ButtonQ12               matlab.ui.control.RadioButton
        ButtonQ13               matlab.ui.control.RadioButton
        ButtonQ14               matlab.ui.control.RadioButton
        ButtonQ15               matlab.ui.control.RadioButton
        ButtonQ16               matlab.ui.control.RadioButton
        ButtonGroupW2           matlab.ui.container.ButtonGroup
        ButtonW21               matlab.ui.control.RadioButton
        ButtonW22               matlab.ui.control.RadioButton
        ButtonGroupE2           matlab.ui.container.ButtonGroup
        ButtonE21               matlab.ui.control.RadioButton
        ButtonE22               matlab.ui.control.RadioButton
        ButtonGroupR2           matlab.ui.container.ButtonGroup
        ButtonR21               matlab.ui.control.RadioButton
        ButtonR22               matlab.ui.control.RadioButton
        ButtonGroupQ2           matlab.ui.container.ButtonGroup
        ButtonQ21               matlab.ui.control.RadioButton
        ButtonQ22               matlab.ui.control.RadioButton
        ButtonQ23               matlab.ui.control.RadioButton
        ButtonQ24               matlab.ui.control.RadioButton
        ButtonQ25               matlab.ui.control.RadioButton
        ButtonQ26               matlab.ui.control.RadioButton
    end

    
    properties (Access = public)
        itemDat  %Item data file
        champDat %Champion data file
        champ1   %Left champion structure
        champ2   %Right champion structure
        side     %Indicator for StatLoad and tracking side updates
        effectOwn%Indicator for tracking effect owner
        itemKey  %Item keys 
        itemName %Item names
        invTemp  %Temporary inventory slot
        slot     %Slot in use
        champ    %Champ structure being referenced
        abiTemp  %Temporary ability slot
        Effect   %Effect structure
        Ref      %Reference for navigating effect structure
    end
    
    methods (Access = public)
        
        function StatLoad(app)
            %This function takes stats out of the champion structures and
            %displays them in the app
            switch app.side
                case 1
                    app.AttackDamageVal.Text = num2str(app.champ1.stats.attackDamage,'%.0f');
                    app.ArmorVal.Text = num2str(app.champ1.stats.armor,'%.0f');
                    app.AttackSpeedVal.Text = num2str(app.champ1.stats.attackSpeed,'%.2f');
                    app.CriticalChanceVal.Text = [num2str(app.champ1.stats.criticalStrikeChance,'%.0f'),'%'];
                    app.AbilityPowerVal.Text = num2str(app.champ1.stats.abilityPower,'%.0f');
                    app.MagicResistanceVal.Text = num2str(app.champ1.stats.magicResistance,'%.0f');
                    app.AbilityHasteVal.Text = num2str(app.champ1.stats.abilityHaste,'%.0f');
                    app.MovementSpeedVal.Text = num2str(app.champ1.stats.movespeed,'%.0f');
                    app.HealthVal.Text = num2str(app.champ1.stats.health,'%.0f');
                    app.ResourceVal.Text = num2str(app.champ1.stats.mana,'%0.f');
                    app.MagicPenVal.Text = [num2str(app.champ1.stats.magicPenetration,'%.0f'),'/',...
                        num2str(app.champ1.stats.magicPenPer,'%.0f'),'%'];
                    app.ArmorPenVal.Text = [num2str(app.champ1.stats.armorPenetration,'%.0f'),'%'];
                    app.LethalityVal.Text = num2str(app.champ1.stats.lethality,'%.0f');
                case 2
                    app.AttackDamageVal_2.Text = num2str(app.champ2.stats.attackDamage,'%.0f');
                    app.ArmorVal_2.Text = num2str(app.champ2.stats.armor,'%.0f');
                    app.AttackSpeedVal_2.Text = num2str(app.champ2.stats.attackSpeed,'%.2f');
                    app.CriticalChanceVal_2.Text = [num2str(app.champ2.stats.criticalStrikeChance,'%.0f'),'%'];
                    app.AbilityPowerVal_2.Text = num2str(app.champ2.stats.abilityPower,'%.0f');
                    app.MagicResistanceVal_2.Text = num2str(app.champ2.stats.magicResistance,'%.0f');
                    app.AbilityHasteVal_2.Text = num2str(app.champ2.stats.abilityHaste,'%.0f');
                    app.MovementSpeedVal_2.Text = num2str(app.champ2.stats.movespeed,'%.0f');
                    app.HealthVal_2.Text = num2str(app.champ2.stats.health,'%.0f');
                    app.ResourceVal_2.Text = num2str(app.champ2.stats.mana,'%0.f');
                    app.MagicPenVal_2.Text = [num2str(app.champ2.stats.magicPenetration,'%.0f'),'/',...
                        num2str(app.champ2.stats.magicPenPer,'%.0f'),'%'];
                    app.ArmorPenVal_2.Text = [num2str(app.champ2.stats.armorPenetration,'%.0f'),'%'];
                    app.LethalityVal_2.Text = num2str(app.champ2.stats.lethality,'%.0f');
            end
            QuickHealth(app,app.champ)
        end
        
        function ItemLoad(app)
            %This function processes user input in order to add items to
            %champion structure inventories. It also updates images.
            ind = strcmp(app.itemName,app.invTemp);
            item = app.itemKey(ind);
            [~,m,b,g] = ItemAdd(app.itemDat,app.(app.champ),item,app.slot);
            if m == 1
                uialert(app.UIFigure,'Only 1 mythic permitted per champion.','Item Alert');
                app.(['Slot',num2str(app.side),num2str(app.slot)]).Value = 'None';
                return
            elseif b == 1
                uialert(app.UIFigure,'Only 1 pair of boots permitted per champion.','Item Alert');
                app.(['Slot',num2str(app.side),num2str(app.slot)]).Value = 'None';
                return
            elseif g == 1
                uialert(app.UIFigure,'Only 1 glory item permitted per champion.','Item Alert');
                app.(['Slot',num2str(app.side),num2str(app.slot)]).Value = 'None';
                return
            end
            slotImName = ['Slot',num2str(app.side),num2str(app.slot),'Im'];
            if strcmp(app.invTemp,'None')
                app.(app.champ) = ItemRemove(app.itemDat,app.(app.champ),app.slot);
                app.(slotImName).ImageSource = 'square.png';
            else
                app.(app.champ) = ItemAdd(app.itemDat,app.(app.champ),item,app.slot);
                app.(slotImName).ImageSource = fullfile('item',[num2str(item),'.png']);
            end
            app.(app.champ) = PassiveUp(app.itemDat,app.(app.champ));
            StatLoad(app)
            MejaiSlider(app)
            if ~isempty(app.Effect)
                if ~isempty(app.abiTemp) && strcmp(app.(app.champ).ch,app.Effect(1).ch) && strcmp(app.abiTemp,app.Effect(1).key)
                    EffectLoad(app)
                end
            end
        end
        
        function AbiLoad(app)
            %This function updates ability icons and structure level by
            %interpreting user input. It also manages max ability levels.
            if any(strcmp(app.(app.champ).ch,{'Karma','Nidalee','Elise'}))
                Karma = 1;
                if app.(app.champ).abi.R == 0
                    app.(app.champ).abi.R = 1;
                end
            else 
                Karma = 0;
            end
            spent = 0;
            abi = 'QWER';
            for i = 1:length(abi)
                spent = spent + app.(app.champ).abi.(abi(i));
            end
            for i = 1:length(abi)
                switch abi(i)
                    case 'R'
                        if Karma == 1
                            maxLevel = floor((app.(app.champ).l-1)/5)+1;
                        elseif strcmp(app.(app.champ).ch,'Udyr')
                            maxLevel = floor((app.(app.champ).l-1)/5);
                        else
                            maxLevel = floor((app.(app.champ).l-1)/5);
                        end
                    otherwise
                        maxLevel = ceil(app.(app.champ).l/2);
                        if strcmp(app.(app.champ).ch,'Udyr')
                            altLevel = 6;
                        else
                            altLevel = 5;
                        end
                        if maxLevel > altLevel
                            maxLevel = altLevel;
                        end
                end
                if app.(app.champ).l - spent + Karma == 0
                    maxLevel = app.(app.champ).abi.(abi(i));
                end
                if maxLevel == 0
                    app.([abi(i),'Spinner',num2str(app.side)]).Visible = 0;
                else
                    app.([abi(i),'Spinner',num2str(app.side)]).Visible = 1;
                    app.([abi(i),'Spinner',num2str(app.side)]).Limits = [0,maxLevel];
                    if Karma == 1 && strcmp(abi(i),'R')
                        if maxLevel == 1
                            app.([abi(i),'Spinner',num2str(app.side)]).Visible = 0;
                        else
                            app.([abi(i),'Spinner',num2str(app.side)]).Limits = [1,maxLevel];
                        end
                    end
                end
            end
            switch app.(app.champ).l - spent + Karma
                case 0
                    app.(['abi',num2str(app.side)]).Text = 'No points left to spend!';
                case 1
                    app.(['abi',num2str(app.side)]).Text = '1 point to spend!';
                otherwise
                    app.(['abi',num2str(app.side)]).Text = [num2str(app.(app.champ).l - spent + Karma),' points to spend!'];
            end
            app.(app.champ) = StackUp(app.(app.champ),app.(['StackSlider',num2str(app.side)]).Value);
            StatLoad(app)
            if ~isempty(app.Effect)
                if ~isempty(app.abiTemp) && strcmp(app.(app.champ).ch,app.Effect(1).ch) && strcmp(app.abiTemp,app.Effect(1).key)
                    EffectLoad(app)
                end
            end
        end
        
        function EffectLoad(app)
            %This function is triggered when an ability icon is pressed and
            %in turn calls AbilityVal. The output structure is interpretted
            %into the UI
            if app.effectOwn == 1
                [effect,~,blurb,name] = AbilityVal(app.champDat,app.champ1,app.abiTemp,app.champ2);
            elseif app.effectOwn == 2
                [effect,~,blurb,name] = AbilityVal(app.champDat,app.champ2,app.abiTemp,app.champ1);
            end
            si = {'2','1'};
            tabCount = 0;
            val = 1;
            if length(name) > 1
                for i = 1:length(app.(['ButtonGroup',app.abiTemp,num2str(app.effectOwn)]).Children)
                    if app.(['Button',app.abiTemp,num2str(app.effectOwn),num2str(i)]).Value
                        val = i;
                    end
                end
                effect = effect(:,:,val);
            end
            app.Effects.Text = name{val};
            if ~isempty(blurb{val})
                app.([app.abiTemp,num2str(app.effectOwn)]).Tooltip = blurb{val};
            end
            for i = 1:numel(effect)
                if ~isempty(effect(i).value)
                    tabCount = tabCount + 1;
                    ref(tabCount) = i;
                    app.(['Tab',num2str(tabCount)]).Parent = app.TabGroup;
                    app.(['Tab',num2str(tabCount)]).Tooltip = effect(i).att;
                    app.(['Tab',num2str(tabCount)]).Title = effect(i).att;
                    app.(['Utility',num2str(tabCount)]).Visible = 0;
                    app.(['Enact',num2str(tabCount)]).Visible = 0;
                    
                    app.(['effectAttributeLabel',num2str(tabCount)]).Visible = 1;
                    app.(['effectAttributeLabel',num2str(tabCount)]).Text = effect(i).att;
                    
                    app.(['effectValueLabel',num2str(tabCount)]).Visible = 1;
                    app.(['effectValueLabel',num2str(tabCount)]).Text = num2str(effect(i).value,'%.1f');
                    
                    if contains(effect(i).att,'amage')
                        app.(['effectDealtLabel',num2str(tabCount)]).Visible = 1;
                        app.(['effectDealtLabel',num2str(tabCount)]).Text = [app.(['champ',si{app.effectOwn}]).ch,' would take ',...
                            num2str(effect(i).dealt,'%.1f'), ' damage.'];
                        app.(['Enact',num2str(tabCount)]).Visible = 1;
                    else
                        app.(['effectDealtLabel',num2str(tabCount)]).Visible = 0;
                    end
                end
            end
            for i = tabCount+1:10
                app.(['Tab',num2str(i)]).Parent = [];
                app.(['Tab',num2str(i)]).Tooltip = '';
                app.(['Tab',num2str(i)]).Title = '';
                app.(['Utility',num2str(i)]).Visible = 0;
                app.(['Enact',num2str(i)]).Visible = 0;
                app.(['effectAttributeLabel',num2str(i)]).Visible = 0;
                app.(['effectValueLabel',num2str(i)]).Visible = 0;
                app.(['effectDealtLabel',num2str(i)]).Visible = 0;
            end
            if tabCount == 0
                i = 1;
                app.Tab1.Parent = app.TabGroup;
                app.Utility1.Visible = 1;
                app.Utility1.Text = 'This is a utility spell!';
                app.(['Tab',num2str(i)]).Tooltip = '';
                app.(['Tab',num2str(i)]).Title = '';
                app.(['Enact',num2str(i)]).Visible = 0;
                app.(['effectAttributeLabel',num2str(i)]).Visible = 0;
                app.(['effectValueLabel',num2str(i)]).Visible = 0;
                app.(['effectDealtLabel',num2str(i)]).Visible = 0;
                app.Effect = [];
                return
            end
            app.Effect = effect;
            app.Ref = ref;
        end
        
        function MejaiSlider(app)
            sli = 'no';
            if any(contains(app.(app.champ).inv,'Dark Seal'))
                sli = 'ds';
            end
            if any(contains(app.(app.champ).inv,'Mejai'))
                sli = 'ms';
            end
            switch sli
                case 'no'
                    app.(['MejaiSlider',num2str(app.side)]).Visible = 0;
                    app.(['MejaiSlider',num2str(app.side),'Label']).Visible = 0;
                case 'ds'
                    app.(['MejaiSlider',num2str(app.side)]).Visible = 1;
                    app.(['MejaiSlider',num2str(app.side),'Label']).Visible = 1;
                    app.(['MejaiSlider',num2str(app.side),'Label']).Text = 'Dark Seal';
                    app.(['MejaiSlider',num2str(app.side)]).Limits = [0,10];
                case 'ms'
                    app.(['MejaiSlider',num2str(app.side)]).Visible = 1;
                    app.(['MejaiSlider',num2str(app.side),'Label']).Visible = 1;
                    app.(['MejaiSlider',num2str(app.side),'Label']).Text = 'Mejai''s';
                    app.(['MejaiSlider',num2str(app.side)]).Limits = [0,25];
            end
        end
        
    end
    
    methods (Access = private)
        function HealthLoad(app,tab)
            chRef = {'champ2','champ1'};
            chTarg = chRef{app.effectOwn};
            app.(chTarg).stats.healthCurrent = app.(chTarg).stats.healthCurrent ...
                - app.Effect(app.Ref(tab)).dealt;
            if app.(chTarg).stats.healthCurrent < 0
                app.(chTarg).stats.healthCurrent = 0;
            end
            QuickHealth(app,chTarg)
            EffectLoad(app)
        end
        
        function QuickHealth(app,chTarg)
            %Updates the visual aspect of health display without changing
            %any stats. chTarg a string 'champ1' or 'champ2'
            app.([chTarg,'Health']).Text = [num2str(app.(chTarg).stats.healthCurrent,'%.0f')...
                ,' / ',num2str(app.(chTarg).stats.health,'%.0f'),' + ',...
                num2str(app.(chTarg).stats.healthRegen/5,'%.1f'),'/s'];
        end
        
        
        function StackSlider(app)
            sli = 0;
            if isfield(app.(app.champ),'Stack')
                sli = 1;
            end
            switch sli
                case 0
                    app.(['StackSlider',num2str(app.side)]).Visible = 0;
                    app.(['StackSlider',num2str(app.side),'Label']).Visible = 0;
                case 1
                    app.(['StackSlider',num2str(app.side)]).Visible = 1;
                    app.(['StackSlider',num2str(app.side),'Label']).Visible = 1;
                    app.(['StackSlider',num2str(app.side),'Label']).Text = app.(app.champ).Stack.name;
                    app.(['StackSlider',num2str(app.side)]).Value = 0;
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load itemdat.mat itemdat itemkey itemname
            load champdat.mat champdat
            %Property definitions
            app.champDat = champdat;
            app.itemDat = itemdat;
            app.itemName = itemname;
            app.itemKey = itemkey;
            %Hiding tabs
            for i = 2:10
                app.(['Tab',num2str(i)]).Parent = [];
            end
            %Initizalizing champ structures and misc visuals
            event.Source.Tag = '1';
                ChampUpdate(app,event)
                MejaiSlider(app)
            event.Source.Tag = '2';
                ChampUpdate(app,event)
                MejaiSlider(app)
        end

        % Value changed function: ChampionName1, ChampionName2
        function ChampUpdate(app, event)
            tag = event.Source.Tag;
            app.side = str2double(tag); app.champ = ['champ',tag];
            app.(['MejaiSlider',num2str(app.side)]).Visible = 0;
            app.(['MejaiSlider',num2str(app.side),'Label']).Visible = 0;
            ch = app.(['ChampionName',tag]).Value;
            app.(app.champ) = ChampGen(app.itemDat,app.champDat,ch);
            app.(['Image',tag]).ImageSource = fullfile('champion',[ch,'.png']);
            abi = 'QWER';
            for i = 1:length(abi)
                [~,~,blurb] = AbilityVal(app.champDat,app.(app.champ),abi(i),app.(app.champ));
                app.([abi(i),num2str(app.side)]).Tooltip = blurb{1};
                if length(app.champDat.(ch).abilities.(abi(i))) > 1
                    app.([abi(i),tag]).ImageSource = fullfile('spell',[ch,abi(i),'1','.png']);
                    n = length(app.champDat.(ch).abilities.(abi(i)));
                    max = 2;
                    if strcmp(abi(i),'Q')
                        max = 6;
                    end
                    app.(['ButtonGroup',abi(i),num2str(app.side)]).Visible = 1;
                    for j = 1:n
                        app.(['Button',abi(i),num2str(app.side),num2str(j)]).Visible = 1;
                    end
                    for j = n+1:max
                        app.(['Button',abi(i),num2str(app.side),num2str(j)]).Visible = 0;
                    end
                else
                    app.(['ButtonGroup',abi(i),num2str(app.side)]).Visible = 0;
                    app.([abi(i),tag]).ImageSource = fullfile('spell',[ch,abi(i),'.png']);
                end
                app.(app.champ).abi.(abi(i)) = 0;
                app.([abi(i),'Spinner',tag]).Limits = [0,1];
                app.([abi(i),'Spinner',tag]).Value = 0;
            end
            for i = 1:6
                app.(['Slot',tag,num2str(i)]).Value = 'None';
                slotImName = ['Slot',num2str(app.side),num2str(i),'Im'];
                app.(slotImName).ImageSource = fullfile('square.png'); 
            end
            %Loading resource bars
            app.(['resourceFull',num2str(app.side)]).Visible = 1;
            switch app.champDat.(ch).resource
                case 'MANA'
                    res = 'mana';
                case 'ENERGY'
                    res = 'energy';
                case 'NONE'
                    res = 'mana';
                    app.(['resourceFull',num2str(app.side)]).Visible = 0;
                otherwise
                    res = 'rage';
            end
            app.(['resourceFull',num2str(app.side)]).ImageSource = ...
                fullfile('StatMods',[res,'Full.png']);
            app.(['healthFull',num2str(app.side)]).ImageSource = ...
                fullfile('StatMods','healthFull.png');
            LevelUpdate(app,event)
            StackSlider(app)
        end

        % Value changed function: LvlDrop1, LvlDrop2
        function LevelUpdate(app, event)
            tag = event.Source.Tag;
            l = str2double(app.(['LvlDrop',tag]).Value);
            app.side = str2double(tag); app.champ = ['champ',tag];
            abi = 'QWER';
            if l < app.(app.champ).l
                for i = 1:length(abi)
                    app.([abi(i),'Spinner',tag]).Value = app.([abi(i),'Spinner',tag]).Limits(1);
                    app.(app.champ).abi.(abi(i)) = app.([abi(i),'Spinner',tag]).Value;
                end
            end
            app.(['champ',tag]) = LevelUp(app.champDat,app.(app.champ),l);
            app.(['champ',tag]).stats.healthCurrent = app.(['champ',tag]).stats.health;
            QuickHealth(app,app.champ)
            AbiLoad(app)
            StatLoad(app)
        end

        % Value changed function: Slot11, Slot12, Slot13, Slot14, 
        % Slot15, Slot16, Slot21, Slot22, Slot23, Slot24, Slot25, 
        % Slot26
        function InvUpdate(app, event)
            tag = event.Source.Tag;
            app.invTemp = app.(['Slot',tag]).Value;
            app.champ = ['champ',tag(1)]; app.slot = str2double(tag(2)); app.side = str2double(tag(1));
            ItemLoad(app)
        end

        % Value changed function: ESpinner1, ESpinner2, QSpinner1, 
        % QSpinner2, RSpinner1, RSpinner2, WSpinner1, WSpinner2
        function AbiUpdate(app, event)
            tag = event.Source.Tag;
            app.(['champ',tag(2)]).abi.(tag(1)) = app.([tag(1),'Spinner',tag(2)]).Value;
            app.side = str2double(tag(2)); app.champ = ['champ',tag(2)];
            AbiLoad(app)
        end

        % Image clicked function: E1, E2, Q1, Q2, R1, R2, W1, W2
        function EffectUpdate(app, event)
            tag = event.Source.Tag;
            app.abiTemp = tag(1);
            app.side = str2double(tag(2)); app.champ = ['champ',tag(2)];
            if app.(app.champ).abi.(app.abiTemp) == 0
                app.Utility1.Text = 'Make sure to level an ability before simulating!';
            else
                app.effectOwn = str2double(tag(2));
                EffectLoad(app)
            end
        end

        % Value changed function: Enact1, Enact10, Enact2, Enact3, 
        % Enact4, Enact5, Enact6, Enact7, Enact8, Enact9
        function EnactEffect(app, event)
            HealthLoad(app,str2double(event.Source.Tag))
        end

        % Image clicked function: Redo1, Redo2
        function HealthReplenish(app, event)
            tag = event.Source.Tag;
            app.(['champ',tag]).stats.healthCurrent = app.(['champ',tag]).stats.health;
            QuickHealth(app,['champ',tag])
            if ~isempty(app.abiTemp)
                EffectLoad(app)
            end
        end

        % Value changed function: MejaiSlider1, MejaiSlider2
        function MejaiUpdate(app, event)
            tag = event.Source.Tag;
            value = app.(['MejaiSlider',tag]).Value;
            app.side = str2double(tag);
            app.champ = ['champ',tag];
            if ~isfield(app.(app.champ).pass,'stack')
                app.(app.champ).pass.stack = 0;
            end
            app.(app.champ).stats.abilityPower = app.(app.champ).stats.abilityPower - ...
                app.(app.champ).pass.stack*app.(app.champ).pass.mod;
            app.(app.champ).pass.stack = value;
            app.(app.champ).stats.abilityPower = app.(app.champ).stats.abilityPower + ...
                app.(app.champ).pass.stack*app.(app.champ).pass.mod;
            AbiLoad(app)
        end

        % Value changed function: StackSlider1, StackSlider2
        function StackUpdate(app, event)
            tag = event.Source.Tag;
            value = app.(['StackSlider',tag]).Value;
            app.side = str2double(tag);
            app.champ = ['champ',tag];
            app.(app.champ) = StackUp(app.(app.champ),value);
            if ~isempty(app.abiTemp)
            EffectLoad(app)
            end
            StatLoad(app)
            app.(app.champ) = PassiveUp(app.itemDat,app.(app.champ));
        end

        % Selection changed function: ButtonGroupE1, ButtonGroupE2, 
        % ButtonGroupQ1, ButtonGroupQ2, ButtonGroupR1, 
        % ButtonGroupR2, ButtonGroupW1, ButtonGroupW2
        function EffectPartition(app, event)
            tag = event.Source.Tag;
            for i = 1:length(app.(['ButtonGroup',tag(1),tag(2)]).Children)
                if app.(['Button',tag,num2str(i)]).Value
                    val = i;
                end
            end
            if ~isempty(app.Effect)
                EffectLoad(app)
            end
            app.(tag).ImageSource = fullfile('spell',...
                [app.(['champ',tag(2)]).ch,tag(1),num2str(val),'.png']);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Image1 = uiimage(app.GridLayout);
            app.HealthLabel = uilabel(app.GridLayout);
            app.HealthLabel.Text = 'Health';
            app.ResourceLabel = uilabel(app.GridLayout);
            app.ResourceLabel.Text = 'Resource';
            app.AttackDamageLabel = uilabel(app.GridLayout);
            app.AttackDamageLabel.Text = 'Attack Damage';
            app.ArmorLabel = uilabel(app.GridLayout);
            app.ArmorLabel.Text = 'Armor';
            app.AttackSpeedLabel = uilabel(app.GridLayout);
            app.AttackSpeedLabel.Text = 'Attack Speed';
            app.CriticalChanceLabel = uilabel(app.GridLayout);
            app.CriticalChanceLabel.Text = 'Critical Chance';
            app.HealthVal = uilabel(app.GridLayout);
            app.HealthVal.Text = '602';
            app.ResourceVal = uilabel(app.GridLayout);
            app.ResourceVal.Text = '0';
            app.AttackDamageVal = uilabel(app.GridLayout);
            app.AttackDamageVal.Text = '58';
            app.ArmorVal = uilabel(app.GridLayout);
            app.ArmorVal.Text = '28';
            app.AttackSpeedVal = uilabel(app.GridLayout);
            app.AttackSpeedVal.Text = '0.66';
            app.CriticalChanceVal = uilabel(app.GridLayout);
            app.CriticalChanceVal.Text = '0%';
            app.AbilityPowerLabel = uilabel(app.GridLayout);
            app.AbilityPowerLabel.Text = 'Ability Power';
            app.MagicResistanceLabel = uilabel(app.GridLayout);
            app.MagicResistanceLabel.Text = 'Magic Resistance';
            app.AbilityHasteLabel = uilabel(app.GridLayout);
            app.AbilityHasteLabel.Text = 'Ability Haste';
            app.MovementSpeedLabel = uilabel(app.GridLayout);
            app.MovementSpeedLabel.Text = 'Movement Speed';
            app.AbilityPowerVal = uilabel(app.GridLayout);
            app.AbilityPowerVal.Text = '0';
            app.MagicResistanceVal = uilabel(app.GridLayout);
            app.MagicResistanceVal.Text = '32';
            app.AbilityHasteVal = uilabel(app.GridLayout);
            app.AbilityHasteVal.Text = '0';
            app.MovementSpeedVal = uilabel(app.GridLayout);
            app.MovementSpeedVal.Text = '340';
            app.Slot11Im = uiimage(app.GridLayout);
            app.Slot11Im.ImageSource = 'square.png';
            app.Slot12Im = uiimage(app.GridLayout);
            app.Slot12Im.ImageSource = 'square.png';
            app.Slot13Im = uiimage(app.GridLayout);
            app.Slot13Im.ImageSource = 'square.png';
            app.Slot14Im = uiimage(app.GridLayout);
            app.Slot14Im.ImageSource = 'square.png';
            app.Slot15Im = uiimage(app.GridLayout);
            app.Slot15Im.ImageSource = 'square.png';
            app.Slot16Im = uiimage(app.GridLayout);
            app.Slot16Im.ImageSource = 'square.png';
            app.Image2_1 = uiimage(app.GridLayout);
            app.Image2_1.ImageSource = 'Health_icon.png';
            app.Image2_2 = uiimage(app.GridLayout);
            app.Image2_2.ImageSource = 'Mana_icon.png';
            app.Image2_3 = uiimage(app.GridLayout);
            app.Image2_3.ImageSource = 'Attack_damage_icon.png';
            app.Image2_4 = uiimage(app.GridLayout);
            app.Image2_4.ImageSource = 'Armor_icon.png';
            app.Image2_5 = uiimage(app.GridLayout);
            app.Image2_5.ImageSource = 'Attack_speed_icon.png';
            app.Image2_6 = uiimage(app.GridLayout);
            app.Image2_6.ImageSource = 'Critical_strike_damage_icon.png';
            app.Image2_7 = uiimage(app.GridLayout);
            app.Image2_7.ImageSource = 'Ability_power_icon.png';
            app.Image2_8 = uiimage(app.GridLayout);
            app.Image2_8.ImageSource = 'Magic_resistance_icon.png';
            app.Image2_9 = uiimage(app.GridLayout);
            app.Image2_9.ImageSource = 'Cooldown_reduction_icon.png';
            app.Image2_10 = uiimage(app.GridLayout);
            app.Image2_10.ImageSource = 'Movement_speed_icon.png';
            app.Image2 = uiimage(app.GridLayout);
            app.HealthLabel_2 = uilabel(app.GridLayout);
            app.HealthLabel_2.Text = 'Health';
            app.ResourceLabel_2 = uilabel(app.GridLayout);
            app.ResourceLabel_2.Text = 'Resource';
            app.AttackDamageLabel_2 = uilabel(app.GridLayout);
            app.AttackDamageLabel_2.Text = 'Attack Damage';
            app.ArmorLabel_2 = uilabel(app.GridLayout);
            app.ArmorLabel_2.Text = 'Armor';
            app.AttackSpeedLabel_2 = uilabel(app.GridLayout);
            app.AttackSpeedLabel_2.Text = 'Attack Speed';
            app.CriticalChanceLabel_2 = uilabel(app.GridLayout);
            app.CriticalChanceLabel_2.Text = 'Critical Chance';
            app.HealthVal_2 = uilabel(app.GridLayout);
            app.HealthVal_2.Text = '602';
            app.ResourceVal_2 = uilabel(app.GridLayout);
            app.ResourceVal_2.Text = '0';
            app.AttackDamageVal_2 = uilabel(app.GridLayout);
            app.AttackDamageVal_2.Text = '58';
            app.ArmorVal_2 = uilabel(app.GridLayout);
            app.ArmorVal_2.Text = '28';
            app.AttackSpeedVal_2 = uilabel(app.GridLayout);
            app.AttackSpeedVal_2.Text = '0.66';
            app.CriticalChanceVal_2 = uilabel(app.GridLayout);
            app.CriticalChanceVal_2.Text = '0%';
            app.AbilityPowerLabel_2 = uilabel(app.GridLayout);
            app.AbilityPowerLabel_2.Text = 'Ability Power';
            app.MagicResistanceLabel_2 = uilabel(app.GridLayout);
            app.MagicResistanceLabel_2.Text = 'Magic Resistance';
            app.AbilityHasteLabel_2 = uilabel(app.GridLayout);
            app.AbilityHasteLabel_2.Text = 'Ability Haste';
            app.MovementSpeedLabel_2 = uilabel(app.GridLayout);
            app.MovementSpeedLabel_2.Text = 'Movement Speed';
            app.AbilityPowerVal_2 = uilabel(app.GridLayout);
            app.AbilityPowerVal_2.Text = '0';
            app.MagicResistanceVal_2 = uilabel(app.GridLayout);
            app.MagicResistanceVal_2.Text = '32';
            app.AbilityHasteVal_2 = uilabel(app.GridLayout);
            app.AbilityHasteVal_2.Text = '0';
            app.MovementSpeedVal_2 = uilabel(app.GridLayout);
            app.MovementSpeedVal_2.Text = '340';
            app.Slot21Im = uiimage(app.GridLayout);
            app.Slot21Im.ImageSource = 'square.png';
            app.Slot22Im = uiimage(app.GridLayout);
            app.Slot22Im.ImageSource = 'square.png';
            app.Slot23Im = uiimage(app.GridLayout);
            app.Slot23Im.ImageSource = 'square.png';
            app.Slot24Im = uiimage(app.GridLayout);
            app.Slot24Im.ImageSource = 'square.png';
            app.Slot25Im = uiimage(app.GridLayout);
            app.Slot25Im.ImageSource = 'square.png';
            app.Slot26Im = uiimage(app.GridLayout);
            app.Slot26Im.ImageSource = 'square.png';
            app.Image2_11 = uiimage(app.GridLayout);
            app.Image2_11.ImageSource = 'Health_icon.png';
            app.Image2_12 = uiimage(app.GridLayout);
            app.Image2_12.ImageSource = 'Mana_icon.png';
            app.Image2_13 = uiimage(app.GridLayout);
            app.Image2_13.ImageSource = 'Attack_damage_icon.png';
            app.Image2_14 = uiimage(app.GridLayout);
            app.Image2_14.ImageSource = 'Armor_icon.png';
            app.Image2_15 = uiimage(app.GridLayout);
            app.Image2_15.ImageSource = 'Attack_speed_icon.png';
            app.Image2_16 = uiimage(app.GridLayout);
            app.Image2_16.ImageSource = 'Critical_strike_damage_icon.png';
            app.Image2_17 = uiimage(app.GridLayout);
            app.Image2_17.ImageSource = 'Ability_power_icon.png';
            app.Image2_18 = uiimage(app.GridLayout);
            app.Image2_18.ImageSource = 'Magic_resistance_icon.png';
            app.Image2_19 = uiimage(app.GridLayout);
            app.Image2_19.ImageSource = 'Cooldown_reduction_icon.png';
            app.Image2_20 = uiimage(app.GridLayout);
            app.Image2_20.ImageSource = 'Movement_speed_icon.png';
            app.Q1 = uiimage(app.GridLayout);
            app.Q1.ImageClickedFcn = createCallbackFcn(app, @EffectUpdate, true);
            app.Q1.Tag = 'Q1';
            app.W1 = uiimage(app.GridLayout);
            app.W1.ImageClickedFcn = createCallbackFcn(app, @EffectUpdate, true);
            app.W1.Tag = 'W1';
            app.E1 = uiimage(app.GridLayout);
            app.E1.ImageClickedFcn = createCallbackFcn(app, @EffectUpdate, true);
            app.E1.Tag = 'E1';
            app.R1 = uiimage(app.GridLayout);
            app.R1.ImageClickedFcn = createCallbackFcn(app, @EffectUpdate, true);
            app.R1.Tag = 'R1';
            app.MagicPenLabel = uilabel(app.GridLayout);
            app.MagicPenLabel.Text = 'Magic Pen';
            app.MagicPenVal = uilabel(app.GridLayout);
            app.MagicPenVal.Text = '0/0%';
            app.ArmorPenLabel = uilabel(app.GridLayout);
            app.ArmorPenLabel.Text = 'Armor Pen';
            app.ArmorPenVal = uilabel(app.GridLayout);
            app.ArmorPenVal.Text = '0%';
            app.LethalityLabel = uilabel(app.GridLayout);
            app.LethalityLabel.Text = 'Lethality';
            app.LethalityVal = uilabel(app.GridLayout);
            app.LethalityVal.Text = '0';
            app.MagicPenLabel_2 = uilabel(app.GridLayout);
            app.MagicPenLabel_2.Text = 'Magic Pen';
            app.MagicPenVal_2 = uilabel(app.GridLayout);
            app.MagicPenVal_2.Text = '0/0%';
            app.ArmorPenLabel_2 = uilabel(app.GridLayout);
            app.ArmorPenLabel_2.Text = 'Armor Pen';
            app.ArmorPenVal_2 = uilabel(app.GridLayout);
            app.ArmorPenVal_2.Text = '0%';
            app.LethalityLabel_2 = uilabel(app.GridLayout);
            app.LethalityLabel_2.Text = 'Lethality';
            app.LethalityVal_2 = uilabel(app.GridLayout);
            app.LethalityVal_2.Text = '0';
            app.Q2 = uiimage(app.GridLayout);
            app.Q2.ImageClickedFcn = createCallbackFcn(app, @EffectUpdate, true);
            app.Q2.Tag = 'Q2';
            app.W2 = uiimage(app.GridLayout);
            app.W2.ImageClickedFcn = createCallbackFcn(app, @EffectUpdate, true);
            app.W2.Tag = 'W2';
            app.E2 = uiimage(app.GridLayout);
            app.E2.ImageClickedFcn = createCallbackFcn(app, @EffectUpdate, true);
            app.E2.Tag = 'E2';
            app.R2 = uiimage(app.GridLayout);
            app.R2.ImageClickedFcn = createCallbackFcn(app, @EffectUpdate, true);
            app.R2.Tag = 'R2';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Tab1 = uitab(app.TabGroup);
            app.effectAttributeLabel1 = uilabel(app.Tab1);
            app.effectAttributeLabel1.Visible = 'off';
            app.effectAttributeLabel1.Text = 'effectAttribute';
            app.effectValueLabel1 = uilabel(app.Tab1);
            app.effectValueLabel1.Visible = 'off';
            app.effectValueLabel1.Text = 'effectValue';
            app.effectDealtLabel1 = uilabel(app.Tab1);
            app.effectDealtLabel1.Visible = 'off';
            app.effectDealtLabel1.Text = 'effectDealt';
            app.Utility1 = uilabel(app.Tab1);
            app.Utility1.Text = 'Press an ability to simulate!';
            app.Enact1 = uibutton(app.Tab1, 'state');
            app.Enact1.ValueChangedFcn = createCallbackFcn(app, @EnactEffect, true);
            app.Enact1.Tag = '1';
            app.Enact1.Visible = 'off';
            app.Enact1.Text = 'Enact';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Tooltip = {''};
            app.effectAttributeLabel2 = uilabel(app.Tab2);
            app.effectAttributeLabel2.Visible = 'off';
            app.effectAttributeLabel2.Text = 'effectAttribute';
            app.effectValueLabel2 = uilabel(app.Tab2);
            app.effectValueLabel2.Visible = 'off';
            app.effectValueLabel2.Text = 'effectValue';
            app.effectDealtLabel2 = uilabel(app.Tab2);
            app.effectDealtLabel2.Visible = 'off';
            app.effectDealtLabel2.Text = 'effectDealt';
            app.Utility2 = uilabel(app.Tab2);
            app.Utility2.Text = 'Press an ability to simulate!';
            app.Enact2 = uibutton(app.Tab2, 'state');
            app.Enact2.ValueChangedFcn = createCallbackFcn(app, @EnactEffect, true);
            app.Enact2.Tag = '2';
            app.Enact2.Visible = 'off';
            app.Enact2.Text = 'Enact';
            app.Tab3 = uitab(app.TabGroup);
            app.effectAttributeLabel3 = uilabel(app.Tab3);
            app.effectAttributeLabel3.Visible = 'off';
            app.effectAttributeLabel3.Text = 'effectAttribute';
            app.effectValueLabel3 = uilabel(app.Tab3);
            app.effectValueLabel3.Visible = 'off';
            app.effectValueLabel3.Text = 'effectValue';
            app.effectDealtLabel3 = uilabel(app.Tab3);
            app.effectDealtLabel3.Visible = 'off';
            app.effectDealtLabel3.Text = 'effectDealt';
            app.Utility3 = uilabel(app.Tab3);
            app.Utility3.Text = 'Press an ability to simulate!';
            app.Enact3 = uibutton(app.Tab3, 'state');
            app.Enact3.ValueChangedFcn = createCallbackFcn(app, @EnactEffect, true);
            app.Enact3.Tag = '3';
            app.Enact3.Visible = 'off';
            app.Enact3.Text = 'Enact';
            app.Tab4 = uitab(app.TabGroup);
            app.effectAttributeLabel4 = uilabel(app.Tab4);
            app.effectAttributeLabel4.Visible = 'off';
            app.effectAttributeLabel4.Text = 'effectAttribute';
            app.effectValueLabel4 = uilabel(app.Tab4);
            app.effectValueLabel4.Visible = 'off';
            app.effectValueLabel4.Text = 'effectValue';
            app.effectDealtLabel4 = uilabel(app.Tab4);
            app.effectDealtLabel4.Visible = 'off';
            app.effectDealtLabel4.Text = 'effectDealt';
            app.Utility4 = uilabel(app.Tab4);
            app.Utility4.Text = 'Press an ability to simulate!';
            app.Enact4 = uibutton(app.Tab4, 'state');
            app.Enact4.ValueChangedFcn = createCallbackFcn(app, @EnactEffect, true);
            app.Enact4.Tag = '4';
            app.Enact4.Visible = 'off';
            app.Enact4.Text = 'Enact';
            app.Tab5 = uitab(app.TabGroup);
            app.effectAttributeLabel5 = uilabel(app.Tab5);
            app.effectAttributeLabel5.Visible = 'off';
            app.effectAttributeLabel5.Text = 'effectAttribute';
            app.effectValueLabel5 = uilabel(app.Tab5);
            app.effectValueLabel5.Visible = 'off';
            app.effectValueLabel5.Text = 'effectValue';
            app.effectDealtLabel5 = uilabel(app.Tab5);
            app.effectDealtLabel5.Visible = 'off';
            app.effectDealtLabel5.Text = 'effectDealt';
            app.Utility5 = uilabel(app.Tab5);
            app.Utility5.Text = 'Press an ability to simulate!';
            app.Enact5 = uibutton(app.Tab5, 'state');
            app.Enact5.ValueChangedFcn = createCallbackFcn(app, @EnactEffect, true);
            app.Enact5.Tag = '5';
            app.Enact5.Visible = 'off';
            app.Enact5.Text = 'Enact';
            app.Tab6 = uitab(app.TabGroup);
            app.effectAttributeLabel6 = uilabel(app.Tab6);
            app.effectAttributeLabel6.Visible = 'off';
            app.effectAttributeLabel6.Text = 'effectAttribute';
            app.effectValueLabel6 = uilabel(app.Tab6);
            app.effectValueLabel6.Visible = 'off';
            app.effectValueLabel6.Text = 'effectValue';
            app.effectDealtLabel6 = uilabel(app.Tab6);
            app.effectDealtLabel6.Visible = 'off';
            app.effectDealtLabel6.Text = 'effectDealt';
            app.Utility6 = uilabel(app.Tab6);
            app.Utility6.Text = 'Press an ability to simulate!';
            app.Enact6 = uibutton(app.Tab6, 'state');
            app.Enact6.ValueChangedFcn = createCallbackFcn(app, @EnactEffect, true);
            app.Enact6.Tag = '6';
            app.Enact6.Visible = 'off';
            app.Enact6.Text = 'Enact';
            app.Tab7 = uitab(app.TabGroup);
            app.effectAttributeLabel7 = uilabel(app.Tab7);
            app.effectAttributeLabel7.Visible = 'off';
            app.effectAttributeLabel7.Text = 'effectAttribute';
            app.effectValueLabel7 = uilabel(app.Tab7);
            app.effectValueLabel7.Visible = 'off';
            app.effectValueLabel7.Text = 'effectValue';
            app.effectDealtLabel7 = uilabel(app.Tab7);
            app.effectDealtLabel7.Visible = 'off';
            app.effectDealtLabel7.Text = 'effectDealt';
            app.Utility7 = uilabel(app.Tab7);
            app.Utility7.Text = 'Press an ability to simulate!';
            app.Enact7 = uibutton(app.Tab7, 'state');
            app.Enact7.ValueChangedFcn = createCallbackFcn(app, @EnactEffect, true);
            app.Enact7.Tag = '7';
            app.Enact7.Visible = 'off';
            app.Enact7.Text = 'Enact';
            app.Tab8 = uitab(app.TabGroup);
            app.effectAttributeLabel8 = uilabel(app.Tab8);
            app.effectAttributeLabel8.Visible = 'off';
            app.effectAttributeLabel8.Text = 'effectAttribute';
            app.effectValueLabel8 = uilabel(app.Tab8);
            app.effectValueLabel8.Visible = 'off';
            app.effectValueLabel8.Text = 'effectValue';
            app.effectDealtLabel8 = uilabel(app.Tab8);
            app.effectDealtLabel8.Visible = 'off';
            app.effectDealtLabel8.Text = 'effectDealt';
            app.Utility8 = uilabel(app.Tab8);
            app.Utility8.Text = 'Press an ability to simulate!';
            app.Enact8 = uibutton(app.Tab8, 'state');
            app.Enact8.ValueChangedFcn = createCallbackFcn(app, @EnactEffect, true);
            app.Enact8.Tag = '8';
            app.Enact8.Visible = 'off';
            app.Enact8.Text = 'Enact';
            app.Tab9 = uitab(app.TabGroup);
            app.effectAttributeLabel9 = uilabel(app.Tab9);
            app.effectAttributeLabel9.Visible = 'off';
            app.effectAttributeLabel9.Text = 'effectAttribute';
            app.effectValueLabel9 = uilabel(app.Tab9);
            app.effectValueLabel9.Visible = 'off';
            app.effectValueLabel9.Text = 'effectValue';
            app.effectDealtLabel9 = uilabel(app.Tab9);
            app.effectDealtLabel9.Visible = 'off';
            app.effectDealtLabel9.Text = 'effectDealt';
            app.Utility9 = uilabel(app.Tab9);
            app.Utility9.Text = 'Press an ability to simulate!';
            app.Enact9 = uibutton(app.Tab9, 'state');
            app.Enact9.ValueChangedFcn = createCallbackFcn(app, @EnactEffect, true);
            app.Enact9.Tag = '9';
            app.Enact9.Visible = 'off';
            app.Enact9.Text = 'Enact';
            app.Tab10 = uitab(app.TabGroup);
            app.effectAttributeLabel10 = uilabel(app.Tab10);
            app.effectAttributeLabel10.Visible = 'off';
            app.effectAttributeLabel10.Text = 'effectAttribute';
            app.effectValueLabel10 = uilabel(app.Tab10);
            app.effectValueLabel10.Visible = 'off';
            app.effectValueLabel10.Text = 'effectValue';
            app.effectDealtLabel10 = uilabel(app.Tab10);
            app.effectDealtLabel10.Visible = 'off';
            app.effectDealtLabel10.Text = 'effectDealt';
            app.Utility10 = uilabel(app.Tab10);
            app.Utility10.Text = 'Press an ability to simulate!';
            app.Enact10 = uibutton(app.Tab10, 'state');
            app.Enact10.ValueChangedFcn = createCallbackFcn(app, @EnactEffect, true);
            app.Enact10.Tag = '10';
            app.Enact10.Visible = 'off';
            app.Enact10.Text = 'Enact';
            app.Slot11 = uidropdown(app.GridLayout);
            app.Slot11.Items = {'None', 'Boots', 'Faerie Charm', 'Rejuvenation Bead', 'Giant''s Belt', 'Cloak of Agility', 'Blasting Wand', 'Sapphire Crystal', 'Ruby Crystal', 'Cloth Armor', 'Chain Vest', 'Null-Magic Mantle', 'Emberknife', 'Long Sword', 'Pickaxe', 'B. F. Sword', 'Hailblade', 'Dagger', 'Recurve Bow', 'Amplifying Tome', 'Vampiric Scepter', 'Doran''s Shield', 'Doran''s Blade', 'Doran''s Ring', 'Negatron Cloak', 'Needlessly Large Rod', 'Dark Seal', 'Cull', 'Health Potion', 'Total Biscuit of Everlasting Will', 'Kircheis Shard', 'Refillable Potion', 'Corrupting Potion', 'Guardian''s Horn', 'Poro-Snax', 'Control Ward', 'Shurelya''s Battlesong', 'Elixir of Iron', 'Elixir of Sorcery', 'Elixir of Wrath', 'Minion Dematerializer', 'Commencing Stopwatch', 'Stopwatch', 'Broken Stopwatch', 'Perfectly Timed Stopwatch', 'Broken Stopwatch', 'Abyssal Mask', 'Archangel''s Staff', 'Manamune', 'Berserker''s Greaves', 'Boots of Swiftness', 'Chemtech Putrifier', 'Sorcerer''s Shoes', 'Glacial Buckler', 'Guardian Angel', 'Infinity Edge', 'Mortal Reminder', 'Last Whisper', 'Lord Dominik''s Regards', 'Seraph''s Embrace', 'Mejai''s Soulstealer', 'Muramana', 'Muramana', 'Phage', 'Phantom Dancer', 'Plated Steelcaps', 'Seraph''s Embrace', 'Zeke''s Convergence', 'Hearthbound Axe', 'Sterak''s Gage', 'Sheen', 'Spirit Visage', 'Winged Moonplate', 'Kindlegem', 'Sunfire Aegis', 'Tear of the Goddess', 'Black Cleaver', 'Bloodthirster', 'Ravenous Hydra', 'Thornmail', 'Bramble Vest', 'Tiamat', 'Trinity Force', 'Warden''s Mail', 'Warmog''s Armor', 'Runaan''s Hurricane', 'Zeal', 'Rabadon''s Deathcap', 'Wit''s End', 'Rapid Firecannon', 'Stormrazor', 'Lich Bane', 'Banshee''s Veil', 'Aegis of the Legion', 'Redemption', 'Fiendish Codex', 'Knight''s Vow', 'Frozen Heart', 'Mercury''s Treads', 'Guardian''s Orb', 'Aether Wisp', 'Forbidden Idol', 'Nashor''s Tooth', 'Rylai''s Crystal Scepter', 'Mobility Boots', 'Executioner''s Calling', 'Guinsoo''s Rageblade', 'Caulfield''s Warhammer', 'Serrated Dirk', 'Void Staff', 'Mercurial Scimitar', 'Quicksilver Sash', 'Youmuu''s Ghostblade', 'Randuin''s Omen', 'Hextech Alternator', 'Hextech Rocketbelt', 'Blade of the Ruined King', 'Hexdrinker', 'Maw of Malmortius', 'Zhonya''s Hourglass', 'Ionian Boots of Lucidity', 'Morellonomicon', 'Guardian''s Blade', 'Umbral Glaive', 'Sanguine Blade', 'Guardian''s Hammer', 'Locket of the Iron Solari', 'Seeker''s Armguard', 'Gargoyle Stoneplate', 'Spectre''s Cowl', 'Mikael''s Blessing', 'Scarecrow Effigy', 'Stealth Ward', 'Farsight Alteration', 'Oracle Lens', '''Your Cut''', 'Ardent Censer', 'Essence Reaver', 'Eye of the Herald', 'Dead Man''s Plate', 'Titanic Hydra', 'Crystalline Bracer', 'Lost Chapter', 'Edge of Night', 'Spellthief''s Edge', 'Frostfang', 'Shard of True Ice', 'Steel Shoulderguards', 'Runesteel Spaulders', 'Pauldrons of Whiterock', 'Relic Shield', 'Targon''s Buckler', 'Bulwark of the Mountain', 'Spectral Sickle', 'Harrowing Crescent', 'Black Mist Scythe', 'Oblivion Orb', 'Imperial Mandate', 'Force of Nature', 'The Golden Spatula', 'Horizon Focus', 'Cosmic Drive', 'Blighting Jewel', 'Verdant Barrier', 'Riftmaker', 'Leeching Leer', 'Night Harvester', 'Demonic Embrace', 'Watchful Wardstone', 'Stirring Wardstone', 'Bandleglass Mirror', 'Vigilant Wardstone', 'Ironspike Whip', 'Silvermere Dawn', 'Death''s Dance', 'Chempunk Chainsword', 'Staff of Flowing Water', 'Moonstone Renewer', 'Goredrinker', 'Stridebreaker', 'Divine Sunderer', 'Liandry''s Anguish', 'Luden''s Tempest', 'Everfrost', 'Bami''s Cinder', 'Frostfire Gauntlet', 'Turbo Chemtank', 'Noonquiver', 'Galeforce', 'Kraken Slayer', 'Immortal Shieldbow', 'Navori Quickblades', 'The Collector', 'Rageknife', 'Duskblade of Draktharr', 'Eclipse', 'Prowler''s Claw', 'Serylda''s Grudge', 'Serpent''s Fang'};
            app.Slot11.Editable = 'on';
            app.Slot11.ValueChangedFcn = createCallbackFcn(app, @InvUpdate, true);
            app.Slot11.Tag = '11';
            app.Slot11.Value = 'None';
            app.Slot12 = uidropdown(app.GridLayout);
            app.Slot12.Items = {'None', 'Boots', 'Faerie Charm', 'Rejuvenation Bead', 'Giant''s Belt', 'Cloak of Agility', 'Blasting Wand', 'Sapphire Crystal', 'Ruby Crystal', 'Cloth Armor', 'Chain Vest', 'Null-Magic Mantle', 'Emberknife', 'Long Sword', 'Pickaxe', 'B. F. Sword', 'Hailblade', 'Dagger', 'Recurve Bow', 'Amplifying Tome', 'Vampiric Scepter', 'Doran''s Shield', 'Doran''s Blade', 'Doran''s Ring', 'Negatron Cloak', 'Needlessly Large Rod', 'Dark Seal', 'Cull', 'Health Potion', 'Total Biscuit of Everlasting Will', 'Kircheis Shard', 'Refillable Potion', 'Corrupting Potion', 'Guardian''s Horn', 'Poro-Snax', 'Control Ward', 'Shurelya''s Battlesong', 'Elixir of Iron', 'Elixir of Sorcery', 'Elixir of Wrath', 'Minion Dematerializer', 'Commencing Stopwatch', 'Stopwatch', 'Broken Stopwatch', 'Perfectly Timed Stopwatch', 'Broken Stopwatch', 'Abyssal Mask', 'Archangel''s Staff', 'Manamune', 'Berserker''s Greaves', 'Boots of Swiftness', 'Chemtech Putrifier', 'Sorcerer''s Shoes', 'Glacial Buckler', 'Guardian Angel', 'Infinity Edge', 'Mortal Reminder', 'Last Whisper', 'Lord Dominik''s Regards', 'Seraph''s Embrace', 'Mejai''s Soulstealer', 'Muramana', 'Muramana', 'Phage', 'Phantom Dancer', 'Plated Steelcaps', 'Seraph''s Embrace', 'Zeke''s Convergence', 'Hearthbound Axe', 'Sterak''s Gage', 'Sheen', 'Spirit Visage', 'Winged Moonplate', 'Kindlegem', 'Sunfire Aegis', 'Tear of the Goddess', 'Black Cleaver', 'Bloodthirster', 'Ravenous Hydra', 'Thornmail', 'Bramble Vest', 'Tiamat', 'Trinity Force', 'Warden''s Mail', 'Warmog''s Armor', 'Runaan''s Hurricane', 'Zeal', 'Rabadon''s Deathcap', 'Wit''s End', 'Rapid Firecannon', 'Stormrazor', 'Lich Bane', 'Banshee''s Veil', 'Aegis of the Legion', 'Redemption', 'Fiendish Codex', 'Knight''s Vow', 'Frozen Heart', 'Mercury''s Treads', 'Guardian''s Orb', 'Aether Wisp', 'Forbidden Idol', 'Nashor''s Tooth', 'Rylai''s Crystal Scepter', 'Mobility Boots', 'Executioner''s Calling', 'Guinsoo''s Rageblade', 'Caulfield''s Warhammer', 'Serrated Dirk', 'Void Staff', 'Mercurial Scimitar', 'Quicksilver Sash', 'Youmuu''s Ghostblade', 'Randuin''s Omen', 'Hextech Alternator', 'Hextech Rocketbelt', 'Blade of the Ruined King', 'Hexdrinker', 'Maw of Malmortius', 'Zhonya''s Hourglass', 'Ionian Boots of Lucidity', 'Morellonomicon', 'Guardian''s Blade', 'Umbral Glaive', 'Sanguine Blade', 'Guardian''s Hammer', 'Locket of the Iron Solari', 'Seeker''s Armguard', 'Gargoyle Stoneplate', 'Spectre''s Cowl', 'Mikael''s Blessing', 'Scarecrow Effigy', 'Stealth Ward', 'Farsight Alteration', 'Oracle Lens', '''Your Cut''', 'Ardent Censer', 'Essence Reaver', 'Eye of the Herald', 'Dead Man''s Plate', 'Titanic Hydra', 'Crystalline Bracer', 'Lost Chapter', 'Edge of Night', 'Spellthief''s Edge', 'Frostfang', 'Shard of True Ice', 'Steel Shoulderguards', 'Runesteel Spaulders', 'Pauldrons of Whiterock', 'Relic Shield', 'Targon''s Buckler', 'Bulwark of the Mountain', 'Spectral Sickle', 'Harrowing Crescent', 'Black Mist Scythe', 'Oblivion Orb', 'Imperial Mandate', 'Force of Nature', 'The Golden Spatula', 'Horizon Focus', 'Cosmic Drive', 'Blighting Jewel', 'Verdant Barrier', 'Riftmaker', 'Leeching Leer', 'Night Harvester', 'Demonic Embrace', 'Watchful Wardstone', 'Stirring Wardstone', 'Bandleglass Mirror', 'Vigilant Wardstone', 'Ironspike Whip', 'Silvermere Dawn', 'Death''s Dance', 'Chempunk Chainsword', 'Staff of Flowing Water', 'Moonstone Renewer', 'Goredrinker', 'Stridebreaker', 'Divine Sunderer', 'Liandry''s Anguish', 'Luden''s Tempest', 'Everfrost', 'Bami''s Cinder', 'Frostfire Gauntlet', 'Turbo Chemtank', 'Noonquiver', 'Galeforce', 'Kraken Slayer', 'Immortal Shieldbow', 'Navori Quickblades', 'The Collector', 'Rageknife', 'Duskblade of Draktharr', 'Eclipse', 'Prowler''s Claw', 'Serylda''s Grudge', 'Serpent''s Fang'};
            app.Slot12.Editable = 'on';
            app.Slot12.ValueChangedFcn = createCallbackFcn(app, @InvUpdate, true);
            app.Slot12.Tag = '12';
            app.Slot12.Value = 'None';
            app.Slot13 = uidropdown(app.GridLayout);
            app.Slot13.Items = {'None', 'Boots', 'Faerie Charm', 'Rejuvenation Bead', 'Giant''s Belt', 'Cloak of Agility', 'Blasting Wand', 'Sapphire Crystal', 'Ruby Crystal', 'Cloth Armor', 'Chain Vest', 'Null-Magic Mantle', 'Emberknife', 'Long Sword', 'Pickaxe', 'B. F. Sword', 'Hailblade', 'Dagger', 'Recurve Bow', 'Amplifying Tome', 'Vampiric Scepter', 'Doran''s Shield', 'Doran''s Blade', 'Doran''s Ring', 'Negatron Cloak', 'Needlessly Large Rod', 'Dark Seal', 'Cull', 'Health Potion', 'Total Biscuit of Everlasting Will', 'Kircheis Shard', 'Refillable Potion', 'Corrupting Potion', 'Guardian''s Horn', 'Poro-Snax', 'Control Ward', 'Shurelya''s Battlesong', 'Elixir of Iron', 'Elixir of Sorcery', 'Elixir of Wrath', 'Minion Dematerializer', 'Commencing Stopwatch', 'Stopwatch', 'Broken Stopwatch', 'Perfectly Timed Stopwatch', 'Broken Stopwatch', 'Abyssal Mask', 'Archangel''s Staff', 'Manamune', 'Berserker''s Greaves', 'Boots of Swiftness', 'Chemtech Putrifier', 'Sorcerer''s Shoes', 'Glacial Buckler', 'Guardian Angel', 'Infinity Edge', 'Mortal Reminder', 'Last Whisper', 'Lord Dominik''s Regards', 'Seraph''s Embrace', 'Mejai''s Soulstealer', 'Muramana', 'Muramana', 'Phage', 'Phantom Dancer', 'Plated Steelcaps', 'Seraph''s Embrace', 'Zeke''s Convergence', 'Hearthbound Axe', 'Sterak''s Gage', 'Sheen', 'Spirit Visage', 'Winged Moonplate', 'Kindlegem', 'Sunfire Aegis', 'Tear of the Goddess', 'Black Cleaver', 'Bloodthirster', 'Ravenous Hydra', 'Thornmail', 'Bramble Vest', 'Tiamat', 'Trinity Force', 'Warden''s Mail', 'Warmog''s Armor', 'Runaan''s Hurricane', 'Zeal', 'Rabadon''s Deathcap', 'Wit''s End', 'Rapid Firecannon', 'Stormrazor', 'Lich Bane', 'Banshee''s Veil', 'Aegis of the Legion', 'Redemption', 'Fiendish Codex', 'Knight''s Vow', 'Frozen Heart', 'Mercury''s Treads', 'Guardian''s Orb', 'Aether Wisp', 'Forbidden Idol', 'Nashor''s Tooth', 'Rylai''s Crystal Scepter', 'Mobility Boots', 'Executioner''s Calling', 'Guinsoo''s Rageblade', 'Caulfield''s Warhammer', 'Serrated Dirk', 'Void Staff', 'Mercurial Scimitar', 'Quicksilver Sash', 'Youmuu''s Ghostblade', 'Randuin''s Omen', 'Hextech Alternator', 'Hextech Rocketbelt', 'Blade of the Ruined King', 'Hexdrinker', 'Maw of Malmortius', 'Zhonya''s Hourglass', 'Ionian Boots of Lucidity', 'Morellonomicon', 'Guardian''s Blade', 'Umbral Glaive', 'Sanguine Blade', 'Guardian''s Hammer', 'Locket of the Iron Solari', 'Seeker''s Armguard', 'Gargoyle Stoneplate', 'Spectre''s Cowl', 'Mikael''s Blessing', 'Scarecrow Effigy', 'Stealth Ward', 'Farsight Alteration', 'Oracle Lens', '''Your Cut''', 'Ardent Censer', 'Essence Reaver', 'Eye of the Herald', 'Dead Man''s Plate', 'Titanic Hydra', 'Crystalline Bracer', 'Lost Chapter', 'Edge of Night', 'Spellthief''s Edge', 'Frostfang', 'Shard of True Ice', 'Steel Shoulderguards', 'Runesteel Spaulders', 'Pauldrons of Whiterock', 'Relic Shield', 'Targon''s Buckler', 'Bulwark of the Mountain', 'Spectral Sickle', 'Harrowing Crescent', 'Black Mist Scythe', 'Oblivion Orb', 'Imperial Mandate', 'Force of Nature', 'The Golden Spatula', 'Horizon Focus', 'Cosmic Drive', 'Blighting Jewel', 'Verdant Barrier', 'Riftmaker', 'Leeching Leer', 'Night Harvester', 'Demonic Embrace', 'Watchful Wardstone', 'Stirring Wardstone', 'Bandleglass Mirror', 'Vigilant Wardstone', 'Ironspike Whip', 'Silvermere Dawn', 'Death''s Dance', 'Chempunk Chainsword', 'Staff of Flowing Water', 'Moonstone Renewer', 'Goredrinker', 'Stridebreaker', 'Divine Sunderer', 'Liandry''s Anguish', 'Luden''s Tempest', 'Everfrost', 'Bami''s Cinder', 'Frostfire Gauntlet', 'Turbo Chemtank', 'Noonquiver', 'Galeforce', 'Kraken Slayer', 'Immortal Shieldbow', 'Navori Quickblades', 'The Collector', 'Rageknife', 'Duskblade of Draktharr', 'Eclipse', 'Prowler''s Claw', 'Serylda''s Grudge', 'Serpent''s Fang'};
            app.Slot13.Editable = 'on';
            app.Slot13.ValueChangedFcn = createCallbackFcn(app, @InvUpdate, true);
            app.Slot13.Tag = '13';
            app.Slot13.Value = 'None';
            app.Slot14 = uidropdown(app.GridLayout);
            app.Slot14.Items = {'None', 'Boots', 'Faerie Charm', 'Rejuvenation Bead', 'Giant''s Belt', 'Cloak of Agility', 'Blasting Wand', 'Sapphire Crystal', 'Ruby Crystal', 'Cloth Armor', 'Chain Vest', 'Null-Magic Mantle', 'Emberknife', 'Long Sword', 'Pickaxe', 'B. F. Sword', 'Hailblade', 'Dagger', 'Recurve Bow', 'Amplifying Tome', 'Vampiric Scepter', 'Doran''s Shield', 'Doran''s Blade', 'Doran''s Ring', 'Negatron Cloak', 'Needlessly Large Rod', 'Dark Seal', 'Cull', 'Health Potion', 'Total Biscuit of Everlasting Will', 'Kircheis Shard', 'Refillable Potion', 'Corrupting Potion', 'Guardian''s Horn', 'Poro-Snax', 'Control Ward', 'Shurelya''s Battlesong', 'Elixir of Iron', 'Elixir of Sorcery', 'Elixir of Wrath', 'Minion Dematerializer', 'Commencing Stopwatch', 'Stopwatch', 'Broken Stopwatch', 'Perfectly Timed Stopwatch', 'Broken Stopwatch', 'Abyssal Mask', 'Archangel''s Staff', 'Manamune', 'Berserker''s Greaves', 'Boots of Swiftness', 'Chemtech Putrifier', 'Sorcerer''s Shoes', 'Glacial Buckler', 'Guardian Angel', 'Infinity Edge', 'Mortal Reminder', 'Last Whisper', 'Lord Dominik''s Regards', 'Seraph''s Embrace', 'Mejai''s Soulstealer', 'Muramana', 'Muramana', 'Phage', 'Phantom Dancer', 'Plated Steelcaps', 'Seraph''s Embrace', 'Zeke''s Convergence', 'Hearthbound Axe', 'Sterak''s Gage', 'Sheen', 'Spirit Visage', 'Winged Moonplate', 'Kindlegem', 'Sunfire Aegis', 'Tear of the Goddess', 'Black Cleaver', 'Bloodthirster', 'Ravenous Hydra', 'Thornmail', 'Bramble Vest', 'Tiamat', 'Trinity Force', 'Warden''s Mail', 'Warmog''s Armor', 'Runaan''s Hurricane', 'Zeal', 'Rabadon''s Deathcap', 'Wit''s End', 'Rapid Firecannon', 'Stormrazor', 'Lich Bane', 'Banshee''s Veil', 'Aegis of the Legion', 'Redemption', 'Fiendish Codex', 'Knight''s Vow', 'Frozen Heart', 'Mercury''s Treads', 'Guardian''s Orb', 'Aether Wisp', 'Forbidden Idol', 'Nashor''s Tooth', 'Rylai''s Crystal Scepter', 'Mobility Boots', 'Executioner''s Calling', 'Guinsoo''s Rageblade', 'Caulfield''s Warhammer', 'Serrated Dirk', 'Void Staff', 'Mercurial Scimitar', 'Quicksilver Sash', 'Youmuu''s Ghostblade', 'Randuin''s Omen', 'Hextech Alternator', 'Hextech Rocketbelt', 'Blade of the Ruined King', 'Hexdrinker', 'Maw of Malmortius', 'Zhonya''s Hourglass', 'Ionian Boots of Lucidity', 'Morellonomicon', 'Guardian''s Blade', 'Umbral Glaive', 'Sanguine Blade', 'Guardian''s Hammer', 'Locket of the Iron Solari', 'Seeker''s Armguard', 'Gargoyle Stoneplate', 'Spectre''s Cowl', 'Mikael''s Blessing', 'Scarecrow Effigy', 'Stealth Ward', 'Farsight Alteration', 'Oracle Lens', '''Your Cut''', 'Ardent Censer', 'Essence Reaver', 'Eye of the Herald', 'Dead Man''s Plate', 'Titanic Hydra', 'Crystalline Bracer', 'Lost Chapter', 'Edge of Night', 'Spellthief''s Edge', 'Frostfang', 'Shard of True Ice', 'Steel Shoulderguards', 'Runesteel Spaulders', 'Pauldrons of Whiterock', 'Relic Shield', 'Targon''s Buckler', 'Bulwark of the Mountain', 'Spectral Sickle', 'Harrowing Crescent', 'Black Mist Scythe', 'Oblivion Orb', 'Imperial Mandate', 'Force of Nature', 'The Golden Spatula', 'Horizon Focus', 'Cosmic Drive', 'Blighting Jewel', 'Verdant Barrier', 'Riftmaker', 'Leeching Leer', 'Night Harvester', 'Demonic Embrace', 'Watchful Wardstone', 'Stirring Wardstone', 'Bandleglass Mirror', 'Vigilant Wardstone', 'Ironspike Whip', 'Silvermere Dawn', 'Death''s Dance', 'Chempunk Chainsword', 'Staff of Flowing Water', 'Moonstone Renewer', 'Goredrinker', 'Stridebreaker', 'Divine Sunderer', 'Liandry''s Anguish', 'Luden''s Tempest', 'Everfrost', 'Bami''s Cinder', 'Frostfire Gauntlet', 'Turbo Chemtank', 'Noonquiver', 'Galeforce', 'Kraken Slayer', 'Immortal Shieldbow', 'Navori Quickblades', 'The Collector', 'Rageknife', 'Duskblade of Draktharr', 'Eclipse', 'Prowler''s Claw', 'Serylda''s Grudge', 'Serpent''s Fang'};
            app.Slot14.Editable = 'on';
            app.Slot14.ValueChangedFcn = createCallbackFcn(app, @InvUpdate, true);
            app.Slot14.Tag = '14';
            app.Slot14.Value = 'None';
            app.Slot15 = uidropdown(app.GridLayout);
            app.Slot15.Items = {'None', 'Boots', 'Faerie Charm', 'Rejuvenation Bead', 'Giant''s Belt', 'Cloak of Agility', 'Blasting Wand', 'Sapphire Crystal', 'Ruby Crystal', 'Cloth Armor', 'Chain Vest', 'Null-Magic Mantle', 'Emberknife', 'Long Sword', 'Pickaxe', 'B. F. Sword', 'Hailblade', 'Dagger', 'Recurve Bow', 'Amplifying Tome', 'Vampiric Scepter', 'Doran''s Shield', 'Doran''s Blade', 'Doran''s Ring', 'Negatron Cloak', 'Needlessly Large Rod', 'Dark Seal', 'Cull', 'Health Potion', 'Total Biscuit of Everlasting Will', 'Kircheis Shard', 'Refillable Potion', 'Corrupting Potion', 'Guardian''s Horn', 'Poro-Snax', 'Control Ward', 'Shurelya''s Battlesong', 'Elixir of Iron', 'Elixir of Sorcery', 'Elixir of Wrath', 'Minion Dematerializer', 'Commencing Stopwatch', 'Stopwatch', 'Broken Stopwatch', 'Perfectly Timed Stopwatch', 'Broken Stopwatch', 'Abyssal Mask', 'Archangel''s Staff', 'Manamune', 'Berserker''s Greaves', 'Boots of Swiftness', 'Chemtech Putrifier', 'Sorcerer''s Shoes', 'Glacial Buckler', 'Guardian Angel', 'Infinity Edge', 'Mortal Reminder', 'Last Whisper', 'Lord Dominik''s Regards', 'Seraph''s Embrace', 'Mejai''s Soulstealer', 'Muramana', 'Muramana', 'Phage', 'Phantom Dancer', 'Plated Steelcaps', 'Seraph''s Embrace', 'Zeke''s Convergence', 'Hearthbound Axe', 'Sterak''s Gage', 'Sheen', 'Spirit Visage', 'Winged Moonplate', 'Kindlegem', 'Sunfire Aegis', 'Tear of the Goddess', 'Black Cleaver', 'Bloodthirster', 'Ravenous Hydra', 'Thornmail', 'Bramble Vest', 'Tiamat', 'Trinity Force', 'Warden''s Mail', 'Warmog''s Armor', 'Runaan''s Hurricane', 'Zeal', 'Rabadon''s Deathcap', 'Wit''s End', 'Rapid Firecannon', 'Stormrazor', 'Lich Bane', 'Banshee''s Veil', 'Aegis of the Legion', 'Redemption', 'Fiendish Codex', 'Knight''s Vow', 'Frozen Heart', 'Mercury''s Treads', 'Guardian''s Orb', 'Aether Wisp', 'Forbidden Idol', 'Nashor''s Tooth', 'Rylai''s Crystal Scepter', 'Mobility Boots', 'Executioner''s Calling', 'Guinsoo''s Rageblade', 'Caulfield''s Warhammer', 'Serrated Dirk', 'Void Staff', 'Mercurial Scimitar', 'Quicksilver Sash', 'Youmuu''s Ghostblade', 'Randuin''s Omen', 'Hextech Alternator', 'Hextech Rocketbelt', 'Blade of the Ruined King', 'Hexdrinker', 'Maw of Malmortius', 'Zhonya''s Hourglass', 'Ionian Boots of Lucidity', 'Morellonomicon', 'Guardian''s Blade', 'Umbral Glaive', 'Sanguine Blade', 'Guardian''s Hammer', 'Locket of the Iron Solari', 'Seeker''s Armguard', 'Gargoyle Stoneplate', 'Spectre''s Cowl', 'Mikael''s Blessing', 'Scarecrow Effigy', 'Stealth Ward', 'Farsight Alteration', 'Oracle Lens', '''Your Cut''', 'Ardent Censer', 'Essence Reaver', 'Eye of the Herald', 'Dead Man''s Plate', 'Titanic Hydra', 'Crystalline Bracer', 'Lost Chapter', 'Edge of Night', 'Spellthief''s Edge', 'Frostfang', 'Shard of True Ice', 'Steel Shoulderguards', 'Runesteel Spaulders', 'Pauldrons of Whiterock', 'Relic Shield', 'Targon''s Buckler', 'Bulwark of the Mountain', 'Spectral Sickle', 'Harrowing Crescent', 'Black Mist Scythe', 'Oblivion Orb', 'Imperial Mandate', 'Force of Nature', 'The Golden Spatula', 'Horizon Focus', 'Cosmic Drive', 'Blighting Jewel', 'Verdant Barrier', 'Riftmaker', 'Leeching Leer', 'Night Harvester', 'Demonic Embrace', 'Watchful Wardstone', 'Stirring Wardstone', 'Bandleglass Mirror', 'Vigilant Wardstone', 'Ironspike Whip', 'Silvermere Dawn', 'Death''s Dance', 'Chempunk Chainsword', 'Staff of Flowing Water', 'Moonstone Renewer', 'Goredrinker', 'Stridebreaker', 'Divine Sunderer', 'Liandry''s Anguish', 'Luden''s Tempest', 'Everfrost', 'Bami''s Cinder', 'Frostfire Gauntlet', 'Turbo Chemtank', 'Noonquiver', 'Galeforce', 'Kraken Slayer', 'Immortal Shieldbow', 'Navori Quickblades', 'The Collector', 'Rageknife', 'Duskblade of Draktharr', 'Eclipse', 'Prowler''s Claw', 'Serylda''s Grudge', 'Serpent''s Fang'};
            app.Slot15.Editable = 'on';
            app.Slot15.ValueChangedFcn = createCallbackFcn(app, @InvUpdate, true);
            app.Slot15.Tag = '15';
            app.Slot15.Value = 'None';
            app.Slot16 = uidropdown(app.GridLayout);
            app.Slot16.Items = {'None', 'Boots', 'Faerie Charm', 'Rejuvenation Bead', 'Giant''s Belt', 'Cloak of Agility', 'Blasting Wand', 'Sapphire Crystal', 'Ruby Crystal', 'Cloth Armor', 'Chain Vest', 'Null-Magic Mantle', 'Emberknife', 'Long Sword', 'Pickaxe', 'B. F. Sword', 'Hailblade', 'Dagger', 'Recurve Bow', 'Amplifying Tome', 'Vampiric Scepter', 'Doran''s Shield', 'Doran''s Blade', 'Doran''s Ring', 'Negatron Cloak', 'Needlessly Large Rod', 'Dark Seal', 'Cull', 'Health Potion', 'Total Biscuit of Everlasting Will', 'Kircheis Shard', 'Refillable Potion', 'Corrupting Potion', 'Guardian''s Horn', 'Poro-Snax', 'Control Ward', 'Shurelya''s Battlesong', 'Elixir of Iron', 'Elixir of Sorcery', 'Elixir of Wrath', 'Minion Dematerializer', 'Commencing Stopwatch', 'Stopwatch', 'Broken Stopwatch', 'Perfectly Timed Stopwatch', 'Broken Stopwatch', 'Abyssal Mask', 'Archangel''s Staff', 'Manamune', 'Berserker''s Greaves', 'Boots of Swiftness', 'Chemtech Putrifier', 'Sorcerer''s Shoes', 'Glacial Buckler', 'Guardian Angel', 'Infinity Edge', 'Mortal Reminder', 'Last Whisper', 'Lord Dominik''s Regards', 'Seraph''s Embrace', 'Mejai''s Soulstealer', 'Muramana', 'Muramana', 'Phage', 'Phantom Dancer', 'Plated Steelcaps', 'Seraph''s Embrace', 'Zeke''s Convergence', 'Hearthbound Axe', 'Sterak''s Gage', 'Sheen', 'Spirit Visage', 'Winged Moonplate', 'Kindlegem', 'Sunfire Aegis', 'Tear of the Goddess', 'Black Cleaver', 'Bloodthirster', 'Ravenous Hydra', 'Thornmail', 'Bramble Vest', 'Tiamat', 'Trinity Force', 'Warden''s Mail', 'Warmog''s Armor', 'Runaan''s Hurricane', 'Zeal', 'Rabadon''s Deathcap', 'Wit''s End', 'Rapid Firecannon', 'Stormrazor', 'Lich Bane', 'Banshee''s Veil', 'Aegis of the Legion', 'Redemption', 'Fiendish Codex', 'Knight''s Vow', 'Frozen Heart', 'Mercury''s Treads', 'Guardian''s Orb', 'Aether Wisp', 'Forbidden Idol', 'Nashor''s Tooth', 'Rylai''s Crystal Scepter', 'Mobility Boots', 'Executioner''s Calling', 'Guinsoo''s Rageblade', 'Caulfield''s Warhammer', 'Serrated Dirk', 'Void Staff', 'Mercurial Scimitar', 'Quicksilver Sash', 'Youmuu''s Ghostblade', 'Randuin''s Omen', 'Hextech Alternator', 'Hextech Rocketbelt', 'Blade of the Ruined King', 'Hexdrinker', 'Maw of Malmortius', 'Zhonya''s Hourglass', 'Ionian Boots of Lucidity', 'Morellonomicon', 'Guardian''s Blade', 'Umbral Glaive', 'Sanguine Blade', 'Guardian''s Hammer', 'Locket of the Iron Solari', 'Seeker''s Armguard', 'Gargoyle Stoneplate', 'Spectre''s Cowl', 'Mikael''s Blessing', 'Scarecrow Effigy', 'Stealth Ward', 'Farsight Alteration', 'Oracle Lens', '''Your Cut''', 'Ardent Censer', 'Essence Reaver', 'Eye of the Herald', 'Dead Man''s Plate', 'Titanic Hydra', 'Crystalline Bracer', 'Lost Chapter', 'Edge of Night', 'Spellthief''s Edge', 'Frostfang', 'Shard of True Ice', 'Steel Shoulderguards', 'Runesteel Spaulders', 'Pauldrons of Whiterock', 'Relic Shield', 'Targon''s Buckler', 'Bulwark of the Mountain', 'Spectral Sickle', 'Harrowing Crescent', 'Black Mist Scythe', 'Oblivion Orb', 'Imperial Mandate', 'Force of Nature', 'The Golden Spatula', 'Horizon Focus', 'Cosmic Drive', 'Blighting Jewel', 'Verdant Barrier', 'Riftmaker', 'Leeching Leer', 'Night Harvester', 'Demonic Embrace', 'Watchful Wardstone', 'Stirring Wardstone', 'Bandleglass Mirror', 'Vigilant Wardstone', 'Ironspike Whip', 'Silvermere Dawn', 'Death''s Dance', 'Chempunk Chainsword', 'Staff of Flowing Water', 'Moonstone Renewer', 'Goredrinker', 'Stridebreaker', 'Divine Sunderer', 'Liandry''s Anguish', 'Luden''s Tempest', 'Everfrost', 'Bami''s Cinder', 'Frostfire Gauntlet', 'Turbo Chemtank', 'Noonquiver', 'Galeforce', 'Kraken Slayer', 'Immortal Shieldbow', 'Navori Quickblades', 'The Collector', 'Rageknife', 'Duskblade of Draktharr', 'Eclipse', 'Prowler''s Claw', 'Serylda''s Grudge', 'Serpent''s Fang'};
            app.Slot16.Editable = 'on';
            app.Slot16.ValueChangedFcn = createCallbackFcn(app, @InvUpdate, true);
            app.Slot16.Tag = '16';
            app.Slot16.Value = 'None';
            app.Slot21 = uidropdown(app.GridLayout);
            app.Slot21.Items = {'None', 'Boots', 'Faerie Charm', 'Rejuvenation Bead', 'Giant''s Belt', 'Cloak of Agility', 'Blasting Wand', 'Sapphire Crystal', 'Ruby Crystal', 'Cloth Armor', 'Chain Vest', 'Null-Magic Mantle', 'Emberknife', 'Long Sword', 'Pickaxe', 'B. F. Sword', 'Hailblade', 'Dagger', 'Recurve Bow', 'Amplifying Tome', 'Vampiric Scepter', 'Doran''s Shield', 'Doran''s Blade', 'Doran''s Ring', 'Negatron Cloak', 'Needlessly Large Rod', 'Dark Seal', 'Cull', 'Health Potion', 'Total Biscuit of Everlasting Will', 'Kircheis Shard', 'Refillable Potion', 'Corrupting Potion', 'Guardian''s Horn', 'Poro-Snax', 'Control Ward', 'Shurelya''s Battlesong', 'Elixir of Iron', 'Elixir of Sorcery', 'Elixir of Wrath', 'Minion Dematerializer', 'Commencing Stopwatch', 'Stopwatch', 'Broken Stopwatch', 'Perfectly Timed Stopwatch', 'Broken Stopwatch', 'Abyssal Mask', 'Archangel''s Staff', 'Manamune', 'Berserker''s Greaves', 'Boots of Swiftness', 'Chemtech Putrifier', 'Sorcerer''s Shoes', 'Glacial Buckler', 'Guardian Angel', 'Infinity Edge', 'Mortal Reminder', 'Last Whisper', 'Lord Dominik''s Regards', 'Seraph''s Embrace', 'Mejai''s Soulstealer', 'Muramana', 'Muramana', 'Phage', 'Phantom Dancer', 'Plated Steelcaps', 'Seraph''s Embrace', 'Zeke''s Convergence', 'Hearthbound Axe', 'Sterak''s Gage', 'Sheen', 'Spirit Visage', 'Winged Moonplate', 'Kindlegem', 'Sunfire Aegis', 'Tear of the Goddess', 'Black Cleaver', 'Bloodthirster', 'Ravenous Hydra', 'Thornmail', 'Bramble Vest', 'Tiamat', 'Trinity Force', 'Warden''s Mail', 'Warmog''s Armor', 'Runaan''s Hurricane', 'Zeal', 'Rabadon''s Deathcap', 'Wit''s End', 'Rapid Firecannon', 'Stormrazor', 'Lich Bane', 'Banshee''s Veil', 'Aegis of the Legion', 'Redemption', 'Fiendish Codex', 'Knight''s Vow', 'Frozen Heart', 'Mercury''s Treads', 'Guardian''s Orb', 'Aether Wisp', 'Forbidden Idol', 'Nashor''s Tooth', 'Rylai''s Crystal Scepter', 'Mobility Boots', 'Executioner''s Calling', 'Guinsoo''s Rageblade', 'Caulfield''s Warhammer', 'Serrated Dirk', 'Void Staff', 'Mercurial Scimitar', 'Quicksilver Sash', 'Youmuu''s Ghostblade', 'Randuin''s Omen', 'Hextech Alternator', 'Hextech Rocketbelt', 'Blade of the Ruined King', 'Hexdrinker', 'Maw of Malmortius', 'Zhonya''s Hourglass', 'Ionian Boots of Lucidity', 'Morellonomicon', 'Guardian''s Blade', 'Umbral Glaive', 'Sanguine Blade', 'Guardian''s Hammer', 'Locket of the Iron Solari', 'Seeker''s Armguard', 'Gargoyle Stoneplate', 'Spectre''s Cowl', 'Mikael''s Blessing', 'Scarecrow Effigy', 'Stealth Ward', 'Farsight Alteration', 'Oracle Lens', '''Your Cut''', 'Ardent Censer', 'Essence Reaver', 'Eye of the Herald', 'Dead Man''s Plate', 'Titanic Hydra', 'Crystalline Bracer', 'Lost Chapter', 'Edge of Night', 'Spellthief''s Edge', 'Frostfang', 'Shard of True Ice', 'Steel Shoulderguards', 'Runesteel Spaulders', 'Pauldrons of Whiterock', 'Relic Shield', 'Targon''s Buckler', 'Bulwark of the Mountain', 'Spectral Sickle', 'Harrowing Crescent', 'Black Mist Scythe', 'Oblivion Orb', 'Imperial Mandate', 'Force of Nature', 'The Golden Spatula', 'Horizon Focus', 'Cosmic Drive', 'Blighting Jewel', 'Verdant Barrier', 'Riftmaker', 'Leeching Leer', 'Night Harvester', 'Demonic Embrace', 'Watchful Wardstone', 'Stirring Wardstone', 'Bandleglass Mirror', 'Vigilant Wardstone', 'Ironspike Whip', 'Silvermere Dawn', 'Death''s Dance', 'Chempunk Chainsword', 'Staff of Flowing Water', 'Moonstone Renewer', 'Goredrinker', 'Stridebreaker', 'Divine Sunderer', 'Liandry''s Anguish', 'Luden''s Tempest', 'Everfrost', 'Bami''s Cinder', 'Frostfire Gauntlet', 'Turbo Chemtank', 'Noonquiver', 'Galeforce', 'Kraken Slayer', 'Immortal Shieldbow', 'Navori Quickblades', 'The Collector', 'Rageknife', 'Duskblade of Draktharr', 'Eclipse', 'Prowler''s Claw', 'Serylda''s Grudge', 'Serpent''s Fang'};
            app.Slot21.Editable = 'on';
            app.Slot21.ValueChangedFcn = createCallbackFcn(app, @InvUpdate, true);
            app.Slot21.Tag = '21';
            app.Slot21.Value = 'None';
            app.Slot22 = uidropdown(app.GridLayout);
            app.Slot22.Items = {'None', 'Boots', 'Faerie Charm', 'Rejuvenation Bead', 'Giant''s Belt', 'Cloak of Agility', 'Blasting Wand', 'Sapphire Crystal', 'Ruby Crystal', 'Cloth Armor', 'Chain Vest', 'Null-Magic Mantle', 'Emberknife', 'Long Sword', 'Pickaxe', 'B. F. Sword', 'Hailblade', 'Dagger', 'Recurve Bow', 'Amplifying Tome', 'Vampiric Scepter', 'Doran''s Shield', 'Doran''s Blade', 'Doran''s Ring', 'Negatron Cloak', 'Needlessly Large Rod', 'Dark Seal', 'Cull', 'Health Potion', 'Total Biscuit of Everlasting Will', 'Kircheis Shard', 'Refillable Potion', 'Corrupting Potion', 'Guardian''s Horn', 'Poro-Snax', 'Control Ward', 'Shurelya''s Battlesong', 'Elixir of Iron', 'Elixir of Sorcery', 'Elixir of Wrath', 'Minion Dematerializer', 'Commencing Stopwatch', 'Stopwatch', 'Broken Stopwatch', 'Perfectly Timed Stopwatch', 'Broken Stopwatch', 'Abyssal Mask', 'Archangel''s Staff', 'Manamune', 'Berserker''s Greaves', 'Boots of Swiftness', 'Chemtech Putrifier', 'Sorcerer''s Shoes', 'Glacial Buckler', 'Guardian Angel', 'Infinity Edge', 'Mortal Reminder', 'Last Whisper', 'Lord Dominik''s Regards', 'Seraph''s Embrace', 'Mejai''s Soulstealer', 'Muramana', 'Muramana', 'Phage', 'Phantom Dancer', 'Plated Steelcaps', 'Seraph''s Embrace', 'Zeke''s Convergence', 'Hearthbound Axe', 'Sterak''s Gage', 'Sheen', 'Spirit Visage', 'Winged Moonplate', 'Kindlegem', 'Sunfire Aegis', 'Tear of the Goddess', 'Black Cleaver', 'Bloodthirster', 'Ravenous Hydra', 'Thornmail', 'Bramble Vest', 'Tiamat', 'Trinity Force', 'Warden''s Mail', 'Warmog''s Armor', 'Runaan''s Hurricane', 'Zeal', 'Rabadon''s Deathcap', 'Wit''s End', 'Rapid Firecannon', 'Stormrazor', 'Lich Bane', 'Banshee''s Veil', 'Aegis of the Legion', 'Redemption', 'Fiendish Codex', 'Knight''s Vow', 'Frozen Heart', 'Mercury''s Treads', 'Guardian''s Orb', 'Aether Wisp', 'Forbidden Idol', 'Nashor''s Tooth', 'Rylai''s Crystal Scepter', 'Mobility Boots', 'Executioner''s Calling', 'Guinsoo''s Rageblade', 'Caulfield''s Warhammer', 'Serrated Dirk', 'Void Staff', 'Mercurial Scimitar', 'Quicksilver Sash', 'Youmuu''s Ghostblade', 'Randuin''s Omen', 'Hextech Alternator', 'Hextech Rocketbelt', 'Blade of the Ruined King', 'Hexdrinker', 'Maw of Malmortius', 'Zhonya''s Hourglass', 'Ionian Boots of Lucidity', 'Morellonomicon', 'Guardian''s Blade', 'Umbral Glaive', 'Sanguine Blade', 'Guardian''s Hammer', 'Locket of the Iron Solari', 'Seeker''s Armguard', 'Gargoyle Stoneplate', 'Spectre''s Cowl', 'Mikael''s Blessing', 'Scarecrow Effigy', 'Stealth Ward', 'Farsight Alteration', 'Oracle Lens', '''Your Cut''', 'Ardent Censer', 'Essence Reaver', 'Eye of the Herald', 'Dead Man''s Plate', 'Titanic Hydra', 'Crystalline Bracer', 'Lost Chapter', 'Edge of Night', 'Spellthief''s Edge', 'Frostfang', 'Shard of True Ice', 'Steel Shoulderguards', 'Runesteel Spaulders', 'Pauldrons of Whiterock', 'Relic Shield', 'Targon''s Buckler', 'Bulwark of the Mountain', 'Spectral Sickle', 'Harrowing Crescent', 'Black Mist Scythe', 'Oblivion Orb', 'Imperial Mandate', 'Force of Nature', 'The Golden Spatula', 'Horizon Focus', 'Cosmic Drive', 'Blighting Jewel', 'Verdant Barrier', 'Riftmaker', 'Leeching Leer', 'Night Harvester', 'Demonic Embrace', 'Watchful Wardstone', 'Stirring Wardstone', 'Bandleglass Mirror', 'Vigilant Wardstone', 'Ironspike Whip', 'Silvermere Dawn', 'Death''s Dance', 'Chempunk Chainsword', 'Staff of Flowing Water', 'Moonstone Renewer', 'Goredrinker', 'Stridebreaker', 'Divine Sunderer', 'Liandry''s Anguish', 'Luden''s Tempest', 'Everfrost', 'Bami''s Cinder', 'Frostfire Gauntlet', 'Turbo Chemtank', 'Noonquiver', 'Galeforce', 'Kraken Slayer', 'Immortal Shieldbow', 'Navori Quickblades', 'The Collector', 'Rageknife', 'Duskblade of Draktharr', 'Eclipse', 'Prowler''s Claw', 'Serylda''s Grudge', 'Serpent''s Fang'};
            app.Slot22.Editable = 'on';
            app.Slot22.ValueChangedFcn = createCallbackFcn(app, @InvUpdate, true);
            app.Slot22.Tag = '22';
            app.Slot22.Value = 'None';
            app.Slot23 = uidropdown(app.GridLayout);
            app.Slot23.Items = {'None', 'Boots', 'Faerie Charm', 'Rejuvenation Bead', 'Giant''s Belt', 'Cloak of Agility', 'Blasting Wand', 'Sapphire Crystal', 'Ruby Crystal', 'Cloth Armor', 'Chain Vest', 'Null-Magic Mantle', 'Emberknife', 'Long Sword', 'Pickaxe', 'B. F. Sword', 'Hailblade', 'Dagger', 'Recurve Bow', 'Amplifying Tome', 'Vampiric Scepter', 'Doran''s Shield', 'Doran''s Blade', 'Doran''s Ring', 'Negatron Cloak', 'Needlessly Large Rod', 'Dark Seal', 'Cull', 'Health Potion', 'Total Biscuit of Everlasting Will', 'Kircheis Shard', 'Refillable Potion', 'Corrupting Potion', 'Guardian''s Horn', 'Poro-Snax', 'Control Ward', 'Shurelya''s Battlesong', 'Elixir of Iron', 'Elixir of Sorcery', 'Elixir of Wrath', 'Minion Dematerializer', 'Commencing Stopwatch', 'Stopwatch', 'Broken Stopwatch', 'Perfectly Timed Stopwatch', 'Broken Stopwatch', 'Abyssal Mask', 'Archangel''s Staff', 'Manamune', 'Berserker''s Greaves', 'Boots of Swiftness', 'Chemtech Putrifier', 'Sorcerer''s Shoes', 'Glacial Buckler', 'Guardian Angel', 'Infinity Edge', 'Mortal Reminder', 'Last Whisper', 'Lord Dominik''s Regards', 'Seraph''s Embrace', 'Mejai''s Soulstealer', 'Muramana', 'Muramana', 'Phage', 'Phantom Dancer', 'Plated Steelcaps', 'Seraph''s Embrace', 'Zeke''s Convergence', 'Hearthbound Axe', 'Sterak''s Gage', 'Sheen', 'Spirit Visage', 'Winged Moonplate', 'Kindlegem', 'Sunfire Aegis', 'Tear of the Goddess', 'Black Cleaver', 'Bloodthirster', 'Ravenous Hydra', 'Thornmail', 'Bramble Vest', 'Tiamat', 'Trinity Force', 'Warden''s Mail', 'Warmog''s Armor', 'Runaan''s Hurricane', 'Zeal', 'Rabadon''s Deathcap', 'Wit''s End', 'Rapid Firecannon', 'Stormrazor', 'Lich Bane', 'Banshee''s Veil', 'Aegis of the Legion', 'Redemption', 'Fiendish Codex', 'Knight''s Vow', 'Frozen Heart', 'Mercury''s Treads', 'Guardian''s Orb', 'Aether Wisp', 'Forbidden Idol', 'Nashor''s Tooth', 'Rylai''s Crystal Scepter', 'Mobility Boots', 'Executioner''s Calling', 'Guinsoo''s Rageblade', 'Caulfield''s Warhammer', 'Serrated Dirk', 'Void Staff', 'Mercurial Scimitar', 'Quicksilver Sash', 'Youmuu''s Ghostblade', 'Randuin''s Omen', 'Hextech Alternator', 'Hextech Rocketbelt', 'Blade of the Ruined King', 'Hexdrinker', 'Maw of Malmortius', 'Zhonya''s Hourglass', 'Ionian Boots of Lucidity', 'Morellonomicon', 'Guardian''s Blade', 'Umbral Glaive', 'Sanguine Blade', 'Guardian''s Hammer', 'Locket of the Iron Solari', 'Seeker''s Armguard', 'Gargoyle Stoneplate', 'Spectre''s Cowl', 'Mikael''s Blessing', 'Scarecrow Effigy', 'Stealth Ward', 'Farsight Alteration', 'Oracle Lens', '''Your Cut''', 'Ardent Censer', 'Essence Reaver', 'Eye of the Herald', 'Dead Man''s Plate', 'Titanic Hydra', 'Crystalline Bracer', 'Lost Chapter', 'Edge of Night', 'Spellthief''s Edge', 'Frostfang', 'Shard of True Ice', 'Steel Shoulderguards', 'Runesteel Spaulders', 'Pauldrons of Whiterock', 'Relic Shield', 'Targon''s Buckler', 'Bulwark of the Mountain', 'Spectral Sickle', 'Harrowing Crescent', 'Black Mist Scythe', 'Oblivion Orb', 'Imperial Mandate', 'Force of Nature', 'The Golden Spatula', 'Horizon Focus', 'Cosmic Drive', 'Blighting Jewel', 'Verdant Barrier', 'Riftmaker', 'Leeching Leer', 'Night Harvester', 'Demonic Embrace', 'Watchful Wardstone', 'Stirring Wardstone', 'Bandleglass Mirror', 'Vigilant Wardstone', 'Ironspike Whip', 'Silvermere Dawn', 'Death''s Dance', 'Chempunk Chainsword', 'Staff of Flowing Water', 'Moonstone Renewer', 'Goredrinker', 'Stridebreaker', 'Divine Sunderer', 'Liandry''s Anguish', 'Luden''s Tempest', 'Everfrost', 'Bami''s Cinder', 'Frostfire Gauntlet', 'Turbo Chemtank', 'Noonquiver', 'Galeforce', 'Kraken Slayer', 'Immortal Shieldbow', 'Navori Quickblades', 'The Collector', 'Rageknife', 'Duskblade of Draktharr', 'Eclipse', 'Prowler''s Claw', 'Serylda''s Grudge', 'Serpent''s Fang'};
            app.Slot23.Editable = 'on';
            app.Slot23.ValueChangedFcn = createCallbackFcn(app, @InvUpdate, true);
            app.Slot23.Tag = '23';
            app.Slot23.Value = 'None';
            app.Slot24 = uidropdown(app.GridLayout);
            app.Slot24.Items = {'None', 'Boots', 'Faerie Charm', 'Rejuvenation Bead', 'Giant''s Belt', 'Cloak of Agility', 'Blasting Wand', 'Sapphire Crystal', 'Ruby Crystal', 'Cloth Armor', 'Chain Vest', 'Null-Magic Mantle', 'Emberknife', 'Long Sword', 'Pickaxe', 'B. F. Sword', 'Hailblade', 'Dagger', 'Recurve Bow', 'Amplifying Tome', 'Vampiric Scepter', 'Doran''s Shield', 'Doran''s Blade', 'Doran''s Ring', 'Negatron Cloak', 'Needlessly Large Rod', 'Dark Seal', 'Cull', 'Health Potion', 'Total Biscuit of Everlasting Will', 'Kircheis Shard', 'Refillable Potion', 'Corrupting Potion', 'Guardian''s Horn', 'Poro-Snax', 'Control Ward', 'Shurelya''s Battlesong', 'Elixir of Iron', 'Elixir of Sorcery', 'Elixir of Wrath', 'Minion Dematerializer', 'Commencing Stopwatch', 'Stopwatch', 'Broken Stopwatch', 'Perfectly Timed Stopwatch', 'Broken Stopwatch', 'Abyssal Mask', 'Archangel''s Staff', 'Manamune', 'Berserker''s Greaves', 'Boots of Swiftness', 'Chemtech Putrifier', 'Sorcerer''s Shoes', 'Glacial Buckler', 'Guardian Angel', 'Infinity Edge', 'Mortal Reminder', 'Last Whisper', 'Lord Dominik''s Regards', 'Seraph''s Embrace', 'Mejai''s Soulstealer', 'Muramana', 'Muramana', 'Phage', 'Phantom Dancer', 'Plated Steelcaps', 'Seraph''s Embrace', 'Zeke''s Convergence', 'Hearthbound Axe', 'Sterak''s Gage', 'Sheen', 'Spirit Visage', 'Winged Moonplate', 'Kindlegem', 'Sunfire Aegis', 'Tear of the Goddess', 'Black Cleaver', 'Bloodthirster', 'Ravenous Hydra', 'Thornmail', 'Bramble Vest', 'Tiamat', 'Trinity Force', 'Warden''s Mail', 'Warmog''s Armor', 'Runaan''s Hurricane', 'Zeal', 'Rabadon''s Deathcap', 'Wit''s End', 'Rapid Firecannon', 'Stormrazor', 'Lich Bane', 'Banshee''s Veil', 'Aegis of the Legion', 'Redemption', 'Fiendish Codex', 'Knight''s Vow', 'Frozen Heart', 'Mercury''s Treads', 'Guardian''s Orb', 'Aether Wisp', 'Forbidden Idol', 'Nashor''s Tooth', 'Rylai''s Crystal Scepter', 'Mobility Boots', 'Executioner''s Calling', 'Guinsoo''s Rageblade', 'Caulfield''s Warhammer', 'Serrated Dirk', 'Void Staff', 'Mercurial Scimitar', 'Quicksilver Sash', 'Youmuu''s Ghostblade', 'Randuin''s Omen', 'Hextech Alternator', 'Hextech Rocketbelt', 'Blade of the Ruined King', 'Hexdrinker', 'Maw of Malmortius', 'Zhonya''s Hourglass', 'Ionian Boots of Lucidity', 'Morellonomicon', 'Guardian''s Blade', 'Umbral Glaive', 'Sanguine Blade', 'Guardian''s Hammer', 'Locket of the Iron Solari', 'Seeker''s Armguard', 'Gargoyle Stoneplate', 'Spectre''s Cowl', 'Mikael''s Blessing', 'Scarecrow Effigy', 'Stealth Ward', 'Farsight Alteration', 'Oracle Lens', '''Your Cut''', 'Ardent Censer', 'Essence Reaver', 'Eye of the Herald', 'Dead Man''s Plate', 'Titanic Hydra', 'Crystalline Bracer', 'Lost Chapter', 'Edge of Night', 'Spellthief''s Edge', 'Frostfang', 'Shard of True Ice', 'Steel Shoulderguards', 'Runesteel Spaulders', 'Pauldrons of Whiterock', 'Relic Shield', 'Targon''s Buckler', 'Bulwark of the Mountain', 'Spectral Sickle', 'Harrowing Crescent', 'Black Mist Scythe', 'Oblivion Orb', 'Imperial Mandate', 'Force of Nature', 'The Golden Spatula', 'Horizon Focus', 'Cosmic Drive', 'Blighting Jewel', 'Verdant Barrier', 'Riftmaker', 'Leeching Leer', 'Night Harvester', 'Demonic Embrace', 'Watchful Wardstone', 'Stirring Wardstone', 'Bandleglass Mirror', 'Vigilant Wardstone', 'Ironspike Whip', 'Silvermere Dawn', 'Death''s Dance', 'Chempunk Chainsword', 'Staff of Flowing Water', 'Moonstone Renewer', 'Goredrinker', 'Stridebreaker', 'Divine Sunderer', 'Liandry''s Anguish', 'Luden''s Tempest', 'Everfrost', 'Bami''s Cinder', 'Frostfire Gauntlet', 'Turbo Chemtank', 'Noonquiver', 'Galeforce', 'Kraken Slayer', 'Immortal Shieldbow', 'Navori Quickblades', 'The Collector', 'Rageknife', 'Duskblade of Draktharr', 'Eclipse', 'Prowler''s Claw', 'Serylda''s Grudge', 'Serpent''s Fang'};
            app.Slot24.Editable = 'on';
            app.Slot24.ValueChangedFcn = createCallbackFcn(app, @InvUpdate, true);
            app.Slot24.Tag = '24';
            app.Slot24.Value = 'None';
            app.Slot25 = uidropdown(app.GridLayout);

            app.Slot25.Items = {'None', 'Boots', 'Faerie Charm', 'Rejuvenation Bead', 'Giant''s Belt', 'Cloak of Agility', 'Blasting Wand', 'Sapphire Crystal', 'Ruby Crystal', 'Cloth Armor', 'Chain Vest', 'Null-Magic Mantle', 'Emberknife', 'Long Sword', 'Pickaxe', 'B. F. Sword', 'Hailblade', 'Dagger', 'Recurve Bow', 'Amplifying Tome', 'Vampiric Scepter', 'Doran''s Shield', 'Doran''s Blade', 'Doran''s Ring', 'Negatron Cloak', 'Needlessly Large Rod', 'Dark Seal', 'Cull', 'Health Potion', 'Total Biscuit of Everlasting Will', 'Kircheis Shard', 'Refillable Potion', 'Corrupting Potion', 'Guardian''s Horn', 'Poro-Snax', 'Control Ward', 'Shurelya''s Battlesong', 'Elixir of Iron', 'Elixir of Sorcery', 'Elixir of Wrath', 'Minion Dematerializer', 'Commencing Stopwatch', 'Stopwatch', 'Broken Stopwatch', 'Perfectly Timed Stopwatch', 'Broken Stopwatch', 'Abyssal Mask', 'Archangel''s Staff', 'Manamune', 'Berserker''s Greaves', 'Boots of Swiftness', 'Chemtech Putrifier', 'Sorcerer''s Shoes', 'Glacial Buckler', 'Guardian Angel', 'Infinity Edge', 'Mortal Reminder', 'Last Whisper', 'Lord Dominik''s Regards', 'Seraph''s Embrace', 'Mejai''s Soulstealer', 'Muramana', 'Muramana', 'Phage', 'Phantom Dancer', 'Plated Steelcaps', 'Seraph''s Embrace', 'Zeke''s Convergence', 'Hearthbound Axe', 'Sterak''s Gage', 'Sheen', 'Spirit Visage', 'Winged Moonplate', 'Kindlegem', 'Sunfire Aegis', 'Tear of the Goddess', 'Black Cleaver', 'Bloodthirster', 'Ravenous Hydra', 'Thornmail', 'Bramble Vest', 'Tiamat', 'Trinity Force', 'Warden''s Mail', 'Warmog''s Armor', 'Runaan''s Hurricane', 'Zeal', 'Rabadon''s Deathcap', 'Wit''s End', 'Rapid Firecannon', 'Stormrazor', 'Lich Bane', 'Banshee''s Veil', 'Aegis of the Legion', 'Redemption', 'Fiendish Codex', 'Knight''s Vow', 'Frozen Heart', 'Mercury''s Treads', 'Guardian''s Orb', 'Aether Wisp', 'Forbidden Idol', 'Nashor''s Tooth', 'Rylai''s Crystal Scepter', 'Mobility Boots', 'Executioner''s Calling', 'Guinsoo''s Rageblade', 'Caulfield''s Warhammer', 'Serrated Dirk', 'Void Staff', 'Mercurial Scimitar', 'Quicksilver Sash', 'Youmuu''s Ghostblade', 'Randuin''s Omen', 'Hextech Alternator', 'Hextech Rocketbelt', 'Blade of the Ruined King', 'Hexdrinker', 'Maw of Malmortius', 'Zhonya''s Hourglass', 'Ionian Boots of Lucidity', 'Morellonomicon', 'Guardian''s Blade', 'Umbral Glaive', 'Sanguine Blade', 'Guardian''s Hammer', 'Locket of the Iron Solari', 'Seeker''s Armguard', 'Gargoyle Stoneplate', 'Spectre''s Cowl', 'Mikael''s Blessing', 'Scarecrow Effigy', 'Stealth Ward', 'Farsight Alteration', 'Oracle Lens', '''Your Cut''', 'Ardent Censer', 'Essence Reaver', 'Eye of the Herald', 'Dead Man''s Plate', 'Titanic Hydra', 'Crystalline Bracer', 'Lost Chapter', 'Edge of Night', 'Spellthief''s Edge', 'Frostfang', 'Shard of True Ice', 'Steel Shoulderguards', 'Runesteel Spaulders', 'Pauldrons of Whiterock', 'Relic Shield', 'Targon''s Buckler', 'Bulwark of the Mountain', 'Spectral Sickle', 'Harrowing Crescent', 'Black Mist Scythe', 'Oblivion Orb', 'Imperial Mandate', 'Force of Nature', 'The Golden Spatula', 'Horizon Focus', 'Cosmic Drive', 'Blighting Jewel', 'Verdant Barrier', 'Riftmaker', 'Leeching Leer', 'Night Harvester', 'Demonic Embrace', 'Watchful Wardstone', 'Stirring Wardstone', 'Bandleglass Mirror', 'Vigilant Wardstone', 'Ironspike Whip', 'Silvermere Dawn', 'Death''s Dance', 'Chempunk Chainsword', 'Staff of Flowing Water', 'Moonstone Renewer', 'Goredrinker', 'Stridebreaker', 'Divine Sunderer', 'Liandry''s Anguish', 'Luden''s Tempest', 'Everfrost', 'Bami''s Cinder', 'Frostfire Gauntlet', 'Turbo Chemtank', 'Noonquiver', 'Galeforce', 'Kraken Slayer', 'Immortal Shieldbow', 'Navori Quickblades', 'The Collector', 'Rageknife', 'Duskblade of Draktharr', 'Eclipse', 'Prowler''s Claw', 'Serylda''s Grudge', 'Serpent''s Fang'};
            app.Slot25.Editable = 'on';
            app.Slot25.ValueChangedFcn = createCallbackFcn(app, @InvUpdate, true);
            app.Slot25.Tag = '25';
            app.Slot25.Value = 'None';
            app.Slot26 = uidropdown(app.GridLayout);
            app.Slot26.Items = {'None', 'Boots', 'Faerie Charm', 'Rejuvenation Bead', 'Giant''s Belt', 'Cloak of Agility', 'Blasting Wand', 'Sapphire Crystal', 'Ruby Crystal', 'Cloth Armor', 'Chain Vest', 'Null-Magic Mantle', 'Emberknife', 'Long Sword', 'Pickaxe', 'B. F. Sword', 'Hailblade', 'Dagger', 'Recurve Bow', 'Amplifying Tome', 'Vampiric Scepter', 'Doran''s Shield', 'Doran''s Blade', 'Doran''s Ring', 'Negatron Cloak', 'Needlessly Large Rod', 'Dark Seal', 'Cull', 'Health Potion', 'Total Biscuit of Everlasting Will', 'Kircheis Shard', 'Refillable Potion', 'Corrupting Potion', 'Guardian''s Horn', 'Poro-Snax', 'Control Ward', 'Shurelya''s Battlesong', 'Elixir of Iron', 'Elixir of Sorcery', 'Elixir of Wrath', 'Minion Dematerializer', 'Commencing Stopwatch', 'Stopwatch', 'Broken Stopwatch', 'Perfectly Timed Stopwatch', 'Broken Stopwatch', 'Abyssal Mask', 'Archangel''s Staff', 'Manamune', 'Berserker''s Greaves', 'Boots of Swiftness', 'Chemtech Putrifier', 'Sorcerer''s Shoes', 'Glacial Buckler', 'Guardian Angel', 'Infinity Edge', 'Mortal Reminder', 'Last Whisper', 'Lord Dominik''s Regards', 'Seraph''s Embrace', 'Mejai''s Soulstealer', 'Muramana', 'Muramana', 'Phage', 'Phantom Dancer', 'Plated Steelcaps', 'Seraph''s Embrace', 'Zeke''s Convergence', 'Hearthbound Axe', 'Sterak''s Gage', 'Sheen', 'Spirit Visage', 'Winged Moonplate', 'Kindlegem', 'Sunfire Aegis', 'Tear of the Goddess', 'Black Cleaver', 'Bloodthirster', 'Ravenous Hydra', 'Thornmail', 'Bramble Vest', 'Tiamat', 'Trinity Force', 'Warden''s Mail', 'Warmog''s Armor', 'Runaan''s Hurricane', 'Zeal', 'Rabadon''s Deathcap', 'Wit''s End', 'Rapid Firecannon', 'Stormrazor', 'Lich Bane', 'Banshee''s Veil', 'Aegis of the Legion', 'Redemption', 'Fiendish Codex', 'Knight''s Vow', 'Frozen Heart', 'Mercury''s Treads', 'Guardian''s Orb', 'Aether Wisp', 'Forbidden Idol', 'Nashor''s Tooth', 'Rylai''s Crystal Scepter', 'Mobility Boots', 'Executioner''s Calling', 'Guinsoo''s Rageblade', 'Caulfield''s Warhammer', 'Serrated Dirk', 'Void Staff', 'Mercurial Scimitar', 'Quicksilver Sash', 'Youmuu''s Ghostblade', 'Randuin''s Omen', 'Hextech Alternator', 'Hextech Rocketbelt', 'Blade of the Ruined King', 'Hexdrinker', 'Maw of Malmortius', 'Zhonya''s Hourglass', 'Ionian Boots of Lucidity', 'Morellonomicon', 'Guardian''s Blade', 'Umbral Glaive', 'Sanguine Blade', 'Guardian''s Hammer', 'Locket of the Iron Solari', 'Seeker''s Armguard', 'Gargoyle Stoneplate', 'Spectre''s Cowl', 'Mikael''s Blessing', 'Scarecrow Effigy', 'Stealth Ward', 'Farsight Alteration', 'Oracle Lens', '''Your Cut''', 'Ardent Censer', 'Essence Reaver', 'Eye of the Herald', 'Dead Man''s Plate', 'Titanic Hydra', 'Crystalline Bracer', 'Lost Chapter', 'Edge of Night', 'Spellthief''s Edge', 'Frostfang', 'Shard of True Ice', 'Steel Shoulderguards', 'Runesteel Spaulders', 'Pauldrons of Whiterock', 'Relic Shield', 'Targon''s Buckler', 'Bulwark of the Mountain', 'Spectral Sickle', 'Harrowing Crescent', 'Black Mist Scythe', 'Oblivion Orb', 'Imperial Mandate', 'Force of Nature', 'The Golden Spatula', 'Horizon Focus', 'Cosmic Drive', 'Blighting Jewel', 'Verdant Barrier', 'Riftmaker', 'Leeching Leer', 'Night Harvester', 'Demonic Embrace', 'Watchful Wardstone', 'Stirring Wardstone', 'Bandleglass Mirror', 'Vigilant Wardstone', 'Ironspike Whip', 'Silvermere Dawn', 'Death''s Dance', 'Chempunk Chainsword', 'Staff of Flowing Water', 'Moonstone Renewer', 'Goredrinker', 'Stridebreaker', 'Divine Sunderer', 'Liandry''s Anguish', 'Luden''s Tempest', 'Everfrost', 'Bami''s Cinder', 'Frostfire Gauntlet', 'Turbo Chemtank', 'Noonquiver', 'Galeforce', 'Kraken Slayer', 'Immortal Shieldbow', 'Navori Quickblades', 'The Collector', 'Rageknife', 'Duskblade of Draktharr', 'Eclipse', 'Prowler''s Claw', 'Serylda''s Grudge', 'Serpent''s Fang'};
            app.Slot26.Editable = 'on';
            app.Slot26.ValueChangedFcn = createCallbackFcn(app, @InvUpdate, true);
            app.Slot26.Tag = '26';
            app.Slot26.Value = 'None';
            app.ChampionName1 = uidropdown(app.GridLayout);
            app.ChampionName1.Items = {'Aatrox', 'Ahri', 'Akali', 'Alistar', 'Amumu', 'Anivia', 'Annie', 'Aphelios', 'Ashe', 'AurelionSol', 'Azir', 'Bard', 'Blitzcrank', 'Brand', 'Braum', 'Caitlyn', 'Camille', 'Cassiopeia', 'Chogath', 'Corki', 'Darius', 'Diana', 'DrMundo', 'Draven', 'Ekko', 'Elise', 'Evelynn', 'Ezreal', 'Fiddlesticks', 'Fiora', 'Fizz', 'Galio', 'Gangplank', 'Garen', 'Gnar', 'Gragas', 'Graves', 'Hecarim', 'Heimerdinger', 'Illaoi', 'Irelia', 'Ivern', 'Janna', 'JarvanIV', 'Jax', 'Jayce', 'Jhin', 'Jinx', 'Kaisa', 'Kalista', 'Karma', 'Karthus', 'Kassadin', 'Katarina', 'Kayle', 'Kayn', 'Kennen', 'Khazix', 'Kindred', 'Kled', 'KogMaw', 'Leblanc', 'LeeSin', 'Leona', 'Lillia', 'Lissandra', 'Lucian', 'Lulu', 'Lux', 'Malphite', 'Malzahar', 'Maokai', 'MasterYi', 'MissFortune', 'Mordekaiser', 'Morgana', 'Nami', 'Nasus', 'Nautilus', 'Neeko', 'Nidalee', 'Nocturne', 'Nunu', 'Olaf', 'Orianna', 'Ornn', 'Pantheon', 'Poppy', 'Pyke', 'Qiyana', 'Quinn', 'Rakan', 'Rammus', 'RekSai', 'Rell', 'Renekton', 'Rengar', 'Riven', 'Rumble', 'Ryze', 'Samira', 'Sejuani', 'Senna', 'Seraphine', 'Sett', 'Shaco', 'Shen', 'Shyvana', 'Singed', 'Sion', 'Sivir', 'Skarner', 'Sona', 'Soraka', 'Swain', 'Sylas', 'Syndra', 'TahmKench', 'Taliyah', 'Talon', 'Taric', 'Teemo', 'Thresh', 'Tristana', 'Trundle', 'Tryndamere', 'TwistedFate', 'Twitch', 'Udyr', 'Urgot', 'Varus', 'Vayne', 'Veigar', 'Velkoz', 'Vi', 'Viktor', 'Vladimir', 'Volibear', 'Warwick', 'MonkeyKing', 'Xayah', 'Xerath', 'XinZhao', 'Yasuo', 'Yone', 'Yorick', 'Yuumi', 'Zac', 'Zed', 'Ziggs', 'Zilean', 'Zoe', 'Zyra'};
            app.ChampionName1.Editable = 'on';
            app.ChampionName1.ValueChangedFcn = createCallbackFcn(app, @ChampUpdate, true);
            app.ChampionName1.Tag = '1';
            app.ChampionName1.Value = 'Lux';
            app.ChampionName2 = uidropdown(app.GridLayout);
            app.ChampionName2.Items = {'Aatrox', 'Ahri', 'Akali', 'Alistar', 'Amumu', 'Anivia', 'Annie', 'Aphelios', 'Ashe', 'AurelionSol', 'Azir', 'Bard', 'Blitzcrank', 'Brand', 'Braum', 'Caitlyn', 'Camille', 'Cassiopeia', 'Chogath', 'Corki', 'Darius', 'Diana', 'DrMundo', 'Draven', 'Ekko', 'Elise', 'Evelynn', 'Ezreal', 'Fiddlesticks', 'Fiora', 'Fizz', 'Galio', 'Gangplank', 'Garen', 'Gnar', 'Gragas', 'Graves', 'Hecarim', 'Heimerdinger', 'Illaoi', 'Irelia', 'Ivern', 'Janna', 'JarvanIV', 'Jax', 'Jayce', 'Jhin', 'Jinx', 'Kaisa', 'Kalista', 'Karma', 'Karthus', 'Kassadin', 'Katarina', 'Kayle', 'Kayn', 'Kennen', 'Khazix', 'Kindred', 'Kled', 'KogMaw', 'Leblanc', 'LeeSin', 'Leona', 'Lillia', 'Lissandra', 'Lucian', 'Lulu', 'Lux', 'Malphite', 'Malzahar', 'Maokai', 'MasterYi', 'MissFortune', 'Mordekaiser', 'Morgana', 'Nami', 'Nasus', 'Nautilus', 'Neeko', 'Nidalee', 'Nocturne', 'Nunu', 'Olaf', 'Orianna', 'Ornn', 'Pantheon', 'Poppy', 'Pyke', 'Qiyana', 'Quinn', 'Rakan', 'Rammus', 'RekSai', 'Rell', 'Renekton', 'Rengar', 'Riven', 'Rumble', 'Ryze', 'Samira', 'Sejuani', 'Senna', 'Seraphine', 'Sett', 'Shaco', 'Shen', 'Shyvana', 'Singed', 'Sion', 'Sivir', 'Skarner', 'Sona', 'Soraka', 'Swain', 'Sylas', 'Syndra', 'TahmKench', 'Taliyah', 'Talon', 'Taric', 'Teemo', 'Thresh', 'Tristana', 'Trundle', 'Tryndamere', 'TwistedFate', 'Twitch', 'Udyr', 'Urgot', 'Varus', 'Vayne', 'Veigar', 'Velkoz', 'Vi', 'Viktor', 'Vladimir', 'Volibear', 'Warwick', 'MonkeyKing', 'Xayah', 'Xerath', 'XinZhao', 'Yasuo', 'Yone', 'Yorick', 'Yuumi', 'Zac', 'Zed', 'Ziggs', 'Zilean', 'Zoe', 'Zyra'};
            app.ChampionName2.Editable = 'on';
            app.ChampionName2.ValueChangedFcn = createCallbackFcn(app, @ChampUpdate, true);
            app.ChampionName2.Tag = '2';
            app.ChampionName2.Value = 'Malzahar';
            app.abi1 = uilabel(app.GridLayout);
            app.abi1.Text = '1 point to spend!';
            app.abi2 = uilabel(app.GridLayout);
            app.abi2.Text = '1 point to spend!';
            app.healthFull1 = uiimage(app.GridLayout);
            app.resourceFull1 = uiimage(app.GridLayout);
            app.healthFull2 = uiimage(app.GridLayout);
            app.resourceFull2 = uiimage(app.GridLayout);
            app.Effects = uilabel(app.GridLayout);
            app.Effects.Text = 'Effects:';
            app.champ2Health = uilabel(app.GridLayout);
            app.champ2Health.Text = '';
            app.champ1Health = uilabel(app.GridLayout);
            app.champ1Health.Text = '';
            app.Redo1 = uiimage(app.GridLayout);
            app.Redo1.ImageClickedFcn = createCallbackFcn(app, @HealthReplenish, true);
            app.Redo1.Tag = '1';
            app.Redo1.ImageSource = 'Redo.png';
            app.Redo2 = uiimage(app.GridLayout);
            app.Redo2.ImageClickedFcn = createCallbackFcn(app, @HealthReplenish, true);
            app.Redo2.Tag = '2';
            app.Redo2.ImageSource = 'Redo.png';
            app.LvlDropDownLabel = uilabel(app.GridLayout);
            app.LvlDropDownLabel.Text = 'Lvl.';
            app.LvlDrop1 = uidropdown(app.GridLayout);
            app.LvlDrop1.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18'};
            app.LvlDrop1.Editable = 'on';
            app.LvlDrop1.ValueChangedFcn = createCallbackFcn(app, @LevelUpdate, true);
            app.LvlDrop1.Tag = '1';
            app.LvlDrop1.Value = '5';
            app.LvlDropDownLabel_2 = uilabel(app.GridLayout);
            app.LvlDropDownLabel_2.Text = 'Lvl.';
            app.LvlDrop2 = uidropdown(app.GridLayout);
            app.LvlDrop2.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18'};
            app.LvlDrop2.Editable = 'on';
            app.LvlDrop2.ValueChangedFcn = createCallbackFcn(app, @LevelUpdate, true);
            app.LvlDrop2.Tag = '2';
            app.LvlDrop2.Value = '7';
            app.QSpinnerLabel = uilabel(app.GridLayout);
            app.QSpinnerLabel.Text = 'Q';
            app.QSpinner1 = uispinner(app.GridLayout);
            app.QSpinner1.ValueChangedFcn = createCallbackFcn(app, @AbiUpdate, true);
            app.QSpinner1.Tag = 'Q1';
            app.QSpinner1.Value = 1;
            app.ESpinnerLabel = uilabel(app.GridLayout);
            app.ESpinnerLabel.Text = 'E';
            app.ESpinner1 = uispinner(app.GridLayout);
            app.ESpinner1.ValueChangedFcn = createCallbackFcn(app, @AbiUpdate, true);
            app.ESpinner1.Tag = 'E1';
            app.WSpinnerLabel = uilabel(app.GridLayout);
            app.WSpinnerLabel.Text = 'W';
            app.WSpinner1 = uispinner(app.GridLayout);
            app.WSpinner1.ValueChangedFcn = createCallbackFcn(app, @AbiUpdate, true);
            app.WSpinner1.Tag = 'W1';
            app.RSpinnerLabel = uilabel(app.GridLayout);
            app.RSpinnerLabel.Text = 'R';
            app.RSpinner1 = uispinner(app.GridLayout);
            app.RSpinner1.Limits = [0 1];
            app.RSpinner1.ValueChangedFcn = createCallbackFcn(app, @AbiUpdate, true);
            app.RSpinner1.Tag = 'R1';
            app.QSpinner_2Label = uilabel(app.GridLayout);
            app.QSpinner_2Label.Text = 'Q';
            app.QSpinner2 = uispinner(app.GridLayout);
            app.QSpinner2.ValueChangedFcn = createCallbackFcn(app, @AbiUpdate, true);
            app.QSpinner2.Tag = 'Q2';
            app.ESpinner_2Label = uilabel(app.GridLayout);
            app.ESpinner_2Label.Text = 'E';
            app.ESpinner2 = uispinner(app.GridLayout);
            app.ESpinner2.ValueChangedFcn = createCallbackFcn(app, @AbiUpdate, true);
            app.ESpinner2.Tag = 'E2';
            app.ESpinner2.Value = 1;
            app.WSpinner_2Label = uilabel(app.GridLayout);
            app.WSpinner_2Label.Text = 'W';
            app.WSpinner2 = uispinner(app.GridLayout);
            app.WSpinner2.ValueChangedFcn = createCallbackFcn(app, @AbiUpdate, true);
            app.WSpinner2.Tag = 'W2';
            app.RSpinner_2Label = uilabel(app.GridLayout);
            app.RSpinner_2Label.Text = 'R';
            app.RSpinner2 = uispinner(app.GridLayout);
            app.RSpinner2.ValueChangedFcn = createCallbackFcn(app, @AbiUpdate, true);
            app.RSpinner2.Tag = 'R2';
            app.Image21 = uiimage(app.GridLayout);
            app.Image21.ImageSource = 'Magic_penetration_icon.png';
            app.Image22 = uiimage(app.GridLayout);
            app.Image22.ImageSource = 'Armor_penetration_icon.png';
            app.Image22_2 = uiimage(app.GridLayout);
            app.Image22_2.ImageSource = 'Armor_penetration_icon.png';
            app.Image22_3 = uiimage(app.GridLayout);
            app.Image22_3.ImageSource = 'Armor_penetration_icon.png';
            app.Image22_4 = uiimage(app.GridLayout);
            app.Image22_4.ImageSource = 'Armor_penetration_icon.png';
            app.Image21_2 = uiimage(app.GridLayout);
            app.Image21_2.ImageSource = 'Magic_penetration_icon.png';
            app.MejaiSlider1Label = uilabel(app.GridLayout);
            app.MejaiSlider1Label.Text = 'Spinner';
            app.MejaiSlider1 = uispinner(app.GridLayout);
            app.MejaiSlider1.ValueChangedFcn = createCallbackFcn(app, @MejaiUpdate, true);
            app.MejaiSlider1.Tag = '1';
            app.StackSlider1Label = uilabel(app.GridLayout);
            app.StackSlider1Label.Text = 'Spinner';
            app.StackSlider1 = uispinner(app.GridLayout);
            app.StackSlider1.ValueChangedFcn = createCallbackFcn(app, @StackUpdate, true);
            app.StackSlider1.Tag = '1';
            app.MejaiSlider2Label = uilabel(app.GridLayout);
            app.MejaiSlider2Label.Text = 'Spinner';
            app.MejaiSlider2 = uispinner(app.GridLayout);
            app.MejaiSlider2.ValueChangedFcn = createCallbackFcn(app, @MejaiUpdate, true);
            app.MejaiSlider2.Tag = '2';
            app.StackSlider2Label = uilabel(app.GridLayout);
            app.StackSlider2Label.Text = 'Spinner';
            app.StackSlider2 = uispinner(app.GridLayout);
            app.StackSlider2.ValueChangedFcn = createCallbackFcn(app, @StackUpdate, true);
            app.StackSlider2.Tag = '2';
            app.Patch111Label = uilabel(app.GridLayout);
            app.Patch111Label.Text = 'Patch 11.1';
            app.ButtonGroupR1 = uibuttongroup(app.GridLayout);
            app.ButtonGroupR1.SelectionChangedFcn = createCallbackFcn(app, @EffectPartition, true);
            app.ButtonGroupR1.Tag = 'R1';
            app.ButtonR11 = uiradiobutton(app.ButtonGroupR1);
            app.ButtonR11.Text = '';
            app.ButtonR11.Value = true;
            app.ButtonR12 = uiradiobutton(app.ButtonGroupR1);
            app.ButtonR12.Text = '';
            app.ButtonGroupE1 = uibuttongroup(app.GridLayout);
            app.ButtonGroupE1.SelectionChangedFcn = createCallbackFcn(app, @EffectPartition, true);
            app.ButtonGroupE1.Tag = 'E1';
            app.ButtonE11 = uiradiobutton(app.ButtonGroupE1);
            app.ButtonE11.Text = '';
            app.ButtonE11.Value = true;
            app.ButtonE12 = uiradiobutton(app.ButtonGroupE1);
            app.ButtonE12.Text = '';
            app.ButtonGroupW1 = uibuttongroup(app.GridLayout);
            app.ButtonGroupW1.SelectionChangedFcn = createCallbackFcn(app, @EffectPartition, true);
            app.ButtonGroupW1.Tag = 'W1';
            app.ButtonW11 = uiradiobutton(app.ButtonGroupW1);
            app.ButtonW11.Text = '';
            app.ButtonW11.Value = true;
            app.ButtonW12 = uiradiobutton(app.ButtonGroupW1);
            app.ButtonW12.Text = '';
            app.ButtonGroupQ1 = uibuttongroup(app.GridLayout);
            app.ButtonGroupQ1.SelectionChangedFcn = createCallbackFcn(app, @EffectPartition, true);
            app.ButtonGroupQ1.Tag = 'Q1';
            app.ButtonQ11 = uiradiobutton(app.ButtonGroupQ1);
            app.ButtonQ11.Text = '';
            app.ButtonQ11.Value = true;
            app.ButtonQ12 = uiradiobutton(app.ButtonGroupQ1);
            app.ButtonQ12.Text = '';
            app.ButtonQ13 = uiradiobutton(app.ButtonGroupQ1);
            app.ButtonQ13.Text = '';
            app.ButtonQ14 = uiradiobutton(app.ButtonGroupQ1);
            app.ButtonQ14.Text = '';
            app.ButtonQ15 = uiradiobutton(app.ButtonGroupQ1);
            app.ButtonQ15.Text = '';
            app.ButtonQ16 = uiradiobutton(app.ButtonGroupQ1);
            app.ButtonQ16.Text = '';
            app.ButtonGroupW2 = uibuttongroup(app.GridLayout);
            app.ButtonGroupW2.SelectionChangedFcn = createCallbackFcn(app, @EffectPartition, true);
            app.ButtonGroupW2.Tag = 'W2';
            app.ButtonW21 = uiradiobutton(app.ButtonGroupW2);
            app.ButtonW21.Text = '';
            app.ButtonW21.Value = true;
            app.ButtonW22 = uiradiobutton(app.ButtonGroupW2);
            app.ButtonW22.Text = '';
            app.ButtonGroupE2 = uibuttongroup(app.GridLayout);
            app.ButtonGroupE2.SelectionChangedFcn = createCallbackFcn(app, @EffectPartition, true);
            app.ButtonGroupE2.Tag = 'E2';
            app.ButtonE21 = uiradiobutton(app.ButtonGroupE2);
            app.ButtonE21.Text = '';
            app.ButtonE21.Value = true;
            app.ButtonE22 = uiradiobutton(app.ButtonGroupE2);
            app.ButtonE22.Text = '';
            app.ButtonGroupR2 = uibuttongroup(app.GridLayout);
            app.ButtonGroupR2.SelectionChangedFcn = createCallbackFcn(app, @EffectPartition, true);
            app.ButtonGroupR2.Tag = 'R2';
            app.ButtonR21 = uiradiobutton(app.ButtonGroupR2);
            app.ButtonR21.Text = '';
            app.ButtonR21.Value = true;
            app.ButtonR22 = uiradiobutton(app.ButtonGroupR2);
            app.ButtonR22.Text = '';
            app.ButtonGroupQ2 = uibuttongroup(app.GridLayout);
            app.ButtonGroupQ2.SelectionChangedFcn = createCallbackFcn(app, @EffectPartition, true);
            app.ButtonGroupQ2.Tag = 'Q2';
            app.ButtonQ21 = uiradiobutton(app.ButtonGroupQ2);
            app.ButtonQ21.Text = '';
            app.ButtonQ21.Value = true;
            app.ButtonQ22 = uiradiobutton(app.ButtonGroupQ2);
            app.ButtonQ22.Text = '';
            app.ButtonQ23 = uiradiobutton(app.ButtonGroupQ2);
            app.ButtonQ23.Text = '';
            app.ButtonQ24 = uiradiobutton(app.ButtonGroupQ2);
            app.ButtonQ24.Text = '';
            app.ButtonQ25 = uiradiobutton(app.ButtonGroupQ2);
            app.ButtonQ25.Text = '';
            app.ButtonQ26 = uiradiobutton(app.ButtonGroupQ2);
            app.ButtonQ26.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DoubleChamp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end