classdef SingleChamp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Image                 matlab.ui.control.Image
        ChampionName          matlab.ui.control.EditField
        LvlEditFieldLabel     matlab.ui.control.Label
        LvlEditField          matlab.ui.control.NumericEditField
        HealthLabel           matlab.ui.control.Label
        ResourceLabel         matlab.ui.control.Label
        AttackDamageLabel     matlab.ui.control.Label
        ArmorLabel            matlab.ui.control.Label
        AttackSpeedLabel      matlab.ui.control.Label
        CriticalChanceLabel   matlab.ui.control.Label
        HealthVal             matlab.ui.control.Label
        ResourceVal           matlab.ui.control.Label
        AttackDamageVal       matlab.ui.control.Label
        ArmorVal              matlab.ui.control.Label
        AttackSpeedVal        matlab.ui.control.Label
        CriticalChanceVal     matlab.ui.control.Label
        AbilityPowerLabel     matlab.ui.control.Label
        MagicResistanceLabel  matlab.ui.control.Label
        AbilityHasteLabel     matlab.ui.control.Label
        MovementSpeedLabel    matlab.ui.control.Label
        AbilityPowerVal       matlab.ui.control.Label
        MagicResistanceVal    matlab.ui.control.Label
        AbilityHasteVal       matlab.ui.control.Label
        MovementSpeedVal      matlab.ui.control.Label
        Slot1Im               matlab.ui.control.Image
        Slot1                 matlab.ui.control.NumericEditField
        Slot2                 matlab.ui.control.NumericEditField
        Slot3                 matlab.ui.control.NumericEditField
        Slot4                 matlab.ui.control.NumericEditField
        Slot5                 matlab.ui.control.NumericEditField
        Slot6                 matlab.ui.control.NumericEditField
        Slot2Im               matlab.ui.control.Image
        Slot3Im               matlab.ui.control.Image
        Slot4Im               matlab.ui.control.Image
        Slot5Im               matlab.ui.control.Image
        Slot6Im               matlab.ui.control.Image
        Image2                matlab.ui.control.Image
        Image2_2              matlab.ui.control.Image
        Image2_3              matlab.ui.control.Image
        Image2_4              matlab.ui.control.Image
        Image2_5              matlab.ui.control.Image
        Image2_6              matlab.ui.control.Image
        Image2_7              matlab.ui.control.Image
        Image2_8              matlab.ui.control.Image
        Image2_9              matlab.ui.control.Image
        Image2_10             matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ChampionName, LvlEditField, 
        % Slot1, Slot2, Slot3, Slot4, Slot5, Slot6
        function UpdateChamp(app, event)
            load itemdat.mat itemdat
            load champdat.mat champdat
            ch = app.ChampionName.Value;
            l = app.LvlEditField.Value;
            if isfield(champdat,ch)
                app.Image.ImageSource = ['/Users/ethangreen/Documents/MATLAB/Dragon/Champion/'...
                    ch,'.png'];
            end
            champ = ChampGen(itemdat,champdat,ch);
            champ = LevelUp(champdat,champ,l);
            for i = 1:6
                slotName = ['Slot',num2str(i)];
                slotImName = ['Slot',num2str(i),'Im'];
                if app.(slotName).Value ~= 0
                    champ = ItemAdd(itemdat,champ,app.(slotName).Value,i);
                    app.(slotImName).ImageSource = ['/Users/ethangreen/Documents/MATLAB/Dragon/item/'...
                    num2str(app.(slotName).Value),'.png'];
                elseif app.(slotName).Value == 0
                    app.(slotImName).ImageSource = '/Users/ethangreen/Documents/MATLAB/Dragon/square.png';
                end
            end
            champ = PassiveUp(itemdat,champ);
            app.AttackDamageVal.Text = num2str(champ.stats.attackDamage,'%.0f');
            app.ArmorVal.Text = num2str(champ.stats.armor,'%.0f');
            app.AttackSpeedVal.Text = num2str(champ.stats.attackSpeed,'%.2f');
            app.CriticalChanceVal.Text = num2str(champ.stats.criticalStrikeChance,'%.0f');
            app.AbilityPowerVal.Text = num2str(champ.stats.abilityPower,'%.0f');
            app.MagicResistanceVal.Text = num2str(champ.stats.magicResistance,'%.0f');
            app.AbilityHasteVal.Text = num2str(champ.stats.abilityHaste,'%.0f');
            app.MovementSpeedVal.Text = num2str(champ.stats.movespeed,'%.0f');
            app.HealthVal.Text = num2str(champ.stats.health,'%.0f');
            app.ResourceVal.Text = num2str(champ.stats.mana,'%0.f');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Katarina.png';
            app.ChampionName = uieditfield(app.UIFigure, 'text');
            app.ChampionName.ValueChangedFcn = createCallbackFcn(app, @UpdateChamp, true);
            app.ChampionName.Value = 'Katarina';
            app.LvlEditFieldLabel = uilabel(app.UIFigure);
            app.LvlEditFieldLabel.Text = 'Lvl';
            app.LvlEditField = uieditfield(app.UIFigure, 'numeric');
            app.LvlEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateChamp, true);
            app.LvlEditField.Value = 1;
            app.HealthLabel = uilabel(app.UIFigure);
            app.HealthLabel.Text = 'Health';
            app.ResourceLabel = uilabel(app.UIFigure);
            app.ResourceLabel.Text = 'Resource';
            app.AttackDamageLabel = uilabel(app.UIFigure);
            app.AttackDamageLabel.Text = 'Attack Damage';
            app.ArmorLabel = uilabel(app.UIFigure);
            app.ArmorLabel.Text = 'Armor';
            app.AttackSpeedLabel = uilabel(app.UIFigure);
            app.AttackSpeedLabel.Text = 'Attack Speed';
            app.CriticalChanceLabel = uilabel(app.UIFigure);
            app.CriticalChanceLabel.Text = 'Critical Chance';
            app.HealthVal = uilabel(app.UIFigure);
            app.HealthVal.Text = '602';
            app.ResourceVal = uilabel(app.UIFigure);
            app.ResourceVal.Text = '0';
            app.AttackDamageVal = uilabel(app.UIFigure);
            app.AttackDamageVal.Text = '58';
            app.ArmorVal = uilabel(app.UIFigure);
            app.ArmorVal.Text = '28';
            app.AttackSpeedVal = uilabel(app.UIFigure);
            app.AttackSpeedVal.Text = '0.66';
            app.CriticalChanceVal = uilabel(app.UIFigure);
            app.CriticalChanceVal.Text = '0';
            app.AbilityPowerLabel = uilabel(app.UIFigure);
            app.AbilityPowerLabel.Text = 'Ability Power';
            app.MagicResistanceLabel = uilabel(app.UIFigure);
            app.MagicResistanceLabel.Text = 'Magic Resistance';
            app.AbilityHasteLabel = uilabel(app.UIFigure);
            app.AbilityHasteLabel.Text = 'Ability Haste';
            app.MovementSpeedLabel = uilabel(app.UIFigure);
            app.MovementSpeedLabel.Text = 'Movement Speed';
            app.AbilityPowerVal = uilabel(app.UIFigure);
            app.AbilityPowerVal.Text = '0';
            app.MagicResistanceVal = uilabel(app.UIFigure);
            app.MagicResistanceVal.Text = '32';
            app.AbilityHasteVal = uilabel(app.UIFigure);
            app.AbilityHasteVal.Text = '0';
            app.MovementSpeedVal = uilabel(app.UIFigure);
            app.MovementSpeedVal.Text = '340';
            app.Slot1Im = uiimage(app.UIFigure);
            app.Slot1Im.ImageSource = 'square.png';
            app.Slot1 = uieditfield(app.UIFigure, 'numeric');
            app.Slot1.ValueChangedFcn = createCallbackFcn(app, @UpdateChamp, true);
            app.Slot2 = uieditfield(app.UIFigure, 'numeric');
            app.Slot2.ValueChangedFcn = createCallbackFcn(app, @UpdateChamp, true);
            app.Slot3 = uieditfield(app.UIFigure, 'numeric');
            app.Slot3.ValueChangedFcn = createCallbackFcn(app, @UpdateChamp, true);
            app.Slot4 = uieditfield(app.UIFigure, 'numeric');
            app.Slot4.ValueChangedFcn = createCallbackFcn(app, @UpdateChamp, true);
            app.Slot5 = uieditfield(app.UIFigure, 'numeric');
            app.Slot5.ValueChangedFcn = createCallbackFcn(app, @UpdateChamp, true);
            app.Slot6 = uieditfield(app.UIFigure, 'numeric');
            app.Slot6.ValueChangedFcn = createCallbackFcn(app, @UpdateChamp, true);
            app.Slot2Im = uiimage(app.UIFigure);
            app.Slot2Im.ImageSource = 'square.png';
            app.Slot3Im = uiimage(app.UIFigure);
            app.Slot3Im.ImageSource = 'square.png';
            app.Slot4Im = uiimage(app.UIFigure);
            app.Slot4Im.ImageSource = 'square.png';
            app.Slot5Im = uiimage(app.UIFigure);
            app.Slot5Im.ImageSource = 'square.png';
            app.Slot6Im = uiimage(app.UIFigure);
            app.Slot6Im.ImageSource = 'square.png';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'Health_icon.png';
            app.Image2_2 = uiimage(app.UIFigure);
            app.Image2_2.ImageSource = 'Mana_icon.png';
            app.Image2_3 = uiimage(app.UIFigure);
            app.Image2_3.ImageSource = 'Attack_damage_icon.png';
            app.Image2_4 = uiimage(app.UIFigure);
            app.Image2_4.ImageSource = 'Armor_icon.png';
            app.Image2_5 = uiimage(app.UIFigure);
            app.Image2_5.ImageSource = 'Attack_speed_icon.png';
            app.Image2_6 = uiimage(app.UIFigure);
            app.Image2_6.ImageSource = 'Critical_strike_damage_icon.png';
            app.Image2_7 = uiimage(app.UIFigure);
            app.Image2_7.ImageSource = 'Ability_power_icon.png';
            app.Image2_8 = uiimage(app.UIFigure);
            app.Image2_8.ImageSource = 'Magic_resistance_icon.png';
            app.Image2_9 = uiimage(app.UIFigure);
            app.Image2_9.ImageSource = 'Cooldown_reduction_icon.png';
            app.Image2_10 = uiimage(app.UIFigure);
            app.Image2_10.ImageSource = 'Movement_speed_icon.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SingleChamp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end